classdef GTS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        BinSetupTab                     matlab.ui.container.Tab
        LayerPropertiesPanel            matlab.ui.container.Panel
        MaterialNameEditFieldLabel      matlab.ui.control.Label
        MaterialNameEditField           matlab.ui.control.EditField
        ThicknessmEditFieldLabel        matlab.ui.control.Label
        ThicknessEditField              matlab.ui.control.NumericEditField
        LayerNumberSpinnerLabel         matlab.ui.control.Label
        LayerNumberSpinner              matlab.ui.control.Spinner
        ThermalConductivityWmKEditFieldLabel  matlab.ui.control.Label
        ThermalConductivityEditField    matlab.ui.control.NumericEditField
        Densitykgm3EditFieldLabel       matlab.ui.control.Label
        DensityEditField                matlab.ui.control.NumericEditField
        SpecificHeatCapacityJkgKEditFieldLabel  matlab.ui.control.Label
        SpecificHeatCapacityEditField   matlab.ui.control.NumericEditField
        LayerApplyButton                matlab.ui.control.Button
        LayerRemoveButton               matlab.ui.control.Button
        WallCheckBox                    matlab.ui.control.CheckBox
        BaseCheckBox                    matlab.ui.control.CheckBox
        RoofCheckBox                    matlab.ui.control.CheckBox
        ResetButton                     matlab.ui.control.Button
        Costm3Label                     matlab.ui.control.Label
        LayerCostEditField              matlab.ui.control.NumericEditField
        ColorDropDownLabel              matlab.ui.control.Label
        ColorDropDown                   matlab.ui.control.DropDown
        NumberofLumpedElementsLabel     matlab.ui.control.Label
        LumpedElementsField             matlab.ui.control.NumericEditField
        ContactConductanceWm2KLabel     matlab.ui.control.Label
        ContactResistanceField          matlab.ui.control.NumericEditField
        BinDiagramPanel                 matlab.ui.container.Panel
        BinDiagram                      matlab.ui.control.UIAxes
        BinGeometryPanel                matlab.ui.container.Panel
        GeometrySetButton               matlab.ui.control.Button
        TESBinHeightHmLabel             matlab.ui.control.Label
        BinHeightEditField              matlab.ui.control.NumericEditField
        TESBinDiameterDmLabel           matlab.ui.control.Label
        BinDiameterEditField            matlab.ui.control.NumericEditField
        BinDimensionsImage              matlab.ui.control.Image
        LayersTree                      matlab.ui.container.Tree
        WallNode                        matlab.ui.container.TreeNode
        BaseNode                        matlab.ui.container.TreeNode
        RoofNode                        matlab.ui.container.TreeNode
        LoadFromFileButton              matlab.ui.control.Button
        SaveToFileButton                matlab.ui.control.Button
        SimulationSetupTab              matlab.ui.container.Tab
        SimulationOptionsTabGroup       matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        ParticleParametersPanel         matlab.ui.container.Panel
        ParticleThermalConductivityWmKLabel_2  matlab.ui.control.Label
        ParticleThermalConductivity     matlab.ui.control.NumericEditField
        PackedParticleBulkDensitykgm3Label_2  matlab.ui.control.Label
        ParticleDensity                 matlab.ui.control.NumericEditField
        ParticleSpecificHeatCapacityJkgKLabel_2  matlab.ui.control.Label
        ParticleHeatCapacity            matlab.ui.control.NumericEditField
        ParticleBulkDynamicViscositym2sLabel_2  matlab.ui.control.Label
        ParticleDynamicViscosity        matlab.ui.control.NumericEditField
        ParticleSetButton               matlab.ui.control.Button
        ParticleDataTree                matlab.ui.container.Tree
        StorageCyclePanel               matlab.ui.container.Panel
        ChargeDurrationhEditFieldLabel  matlab.ui.control.Label
        ChargeDurrationEditField        matlab.ui.control.NumericEditField
        NumberofCyclesSpinnerLabel      matlab.ui.control.Label
        NumberofCyclesSpinner           matlab.ui.control.Spinner
        HoldDurrationhEditFieldLabel    matlab.ui.control.Label
        HoldDurrationEditField          matlab.ui.control.NumericEditField
        DischargeDurrationhEditFieldLabel  matlab.ui.control.Label
        DischargeDurrationEditField     matlab.ui.control.NumericEditField
        CycleSetButton                  matlab.ui.control.Button
        TimestepsEditFieldLabel         matlab.ui.control.Label
        TimestepEditField               matlab.ui.control.NumericEditField
        StorageCycleDataTree            matlab.ui.container.Tree
        InletMassFlowRatekgsEditFieldLabel  matlab.ui.control.Label
        InletMassFlowRateEditField      matlab.ui.control.NumericEditField
        OutletMassFlowRatekgsEditFieldLabel  matlab.ui.control.Label
        OutletMassFlowRateEditField     matlab.ui.control.NumericEditField
        HeatTransferParametersPanel     matlab.ui.container.Panel
        ParticleInletTemperatureCLabel  matlab.ui.control.Label
        ParticleInletTemperature        matlab.ui.control.NumericEditField
        InternalAirTemperatureCLabel    matlab.ui.control.Label
        InternalAirTemperature          matlab.ui.control.NumericEditField
        AmbientHeatTransferCoefficientWm2KLabel_2  matlab.ui.control.Label
        AmbientHeatTransferCoefficient  matlab.ui.control.NumericEditField
        InternalHeatTransferCoefficientWm2KLabel  matlab.ui.control.Label
        InternalHeatTransferCoefficient  matlab.ui.control.NumericEditField
        HeatTransferSetButton           matlab.ui.control.Button
        IncludeInternalRadiationExchangeCheckBox  matlab.ui.control.CheckBox
        HeatTransferDataTree            matlab.ui.container.Tree
        AmbientAirTemperatureCLabel     matlab.ui.control.Label
        AmbientAirTemperature           matlab.ui.control.NumericEditField
        StartSimulationButton           matlab.ui.control.Button
        SelectDataFolderPathButton      matlab.ui.control.Button
        CurrentStatusTextArea           matlab.ui.control.TextArea
        MeshSelectionTab                matlab.ui.container.Tab
        ComingsoonLabel                 matlab.ui.control.Label
        ResultsViewerTab                matlab.ui.container.Tab
        Panel_1                         matlab.ui.container.Panel
        Panel1_TemperatureAnimationButton  matlab.ui.control.Button
        Panel1_NonDimensionalCheckBox   matlab.ui.control.CheckBox
        filenameEditFieldLabel          matlab.ui.control.Label
        Panel1_filenameEditField        matlab.ui.control.EditField
        Panel_2                         matlab.ui.container.Panel
        Panel2_ParticleOutletTemperatureButton  matlab.ui.control.Button
        Panel2_BulkCalculationOverOrificeCheckBox  matlab.ui.control.CheckBox
        Panel2_NonDimensionalCheckBox   matlab.ui.control.CheckBox
        filenameEditField_3Label        matlab.ui.control.Label
        Panel2_filenameEditField        matlab.ui.control.EditField
        Panel_3                         matlab.ui.container.Panel
        Panel3_ParticleDomainHeatLossButton  matlab.ui.control.Button
        Panel3_TotalAllSurfacesCheckBox  matlab.ui.control.CheckBox
        Panel3_TopSurfaceCheckBox       matlab.ui.control.CheckBox
        Panel3_WallCheckBox             matlab.ui.control.CheckBox
        Panel3_BaseCheckBox             matlab.ui.control.CheckBox
        filenameEditField_4Label        matlab.ui.control.Label
        Panel3_filenameEditField        matlab.ui.control.EditField
        Panel3_BaseCheckBox_2           matlab.ui.control.CheckBox
        Panel_4                         matlab.ui.container.Panel
        Panel4_InsulationLayersButton   matlab.ui.control.Button
        Panel4_WallCheckBox             matlab.ui.control.CheckBox
        Panel4_BaseCheckBox             matlab.ui.control.CheckBox
        filenameEditField_5Label        matlab.ui.control.Label
        Panel4_filenameEditField        matlab.ui.control.EditField
        Panel4_TempCheckBox             matlab.ui.control.CheckBox
        Panel4_HeatFluxCheckBox         matlab.ui.control.CheckBox
        Panel4_BaseCheckBox_2           matlab.ui.control.CheckBox
        Panel_5                         matlab.ui.container.Panel
        Panel5_RadialTemperatureLineButton  matlab.ui.control.Button
        VerticalLocationmEditFieldLabel  matlab.ui.control.Label
        Panel5_VerticalLocationEditField  matlab.ui.control.NumericEditField
        filenameEditField_6Label        matlab.ui.control.Label
        Panel5_filenameEditField        matlab.ui.control.EditField
        Panel5_NonDimensionalCheckBox   matlab.ui.control.CheckBox
        Panel_6                         matlab.ui.container.Panel
        Panel6_VerticalTemperatureLineButton  matlab.ui.control.Button
        RadialLocationmEditFieldLabel   matlab.ui.control.Label
        Panel6_RadialLocationEditField  matlab.ui.control.NumericEditField
        filenameEditField_7Label        matlab.ui.control.Label
        Panel6_filenameEditField        matlab.ui.control.EditField
        Panel6_NonDimensionalCheckBox   matlab.ui.control.CheckBox
        Panel_7                         matlab.ui.container.Panel
        Panel7_BulkVolumetricTemperatureButton  matlab.ui.control.Button
        Panel7_NonDimensionalCheckBox   matlab.ui.control.CheckBox
        filenameEditField_2Label        matlab.ui.control.Label
        Panel7_filenameEditField        matlab.ui.control.EditField
        InsulationTestingTab            matlab.ui.container.Tab
        InsulationTestingTabGroup       matlab.ui.container.TabGroup
        WallTab                         matlab.ui.container.Tab
        WallLayerPropertiesPanel        matlab.ui.container.Panel
        MaterialNameEditField_2Label    matlab.ui.control.Label
        MaterialNameEditField_2         matlab.ui.control.EditField
        LayerNumberSpinner_2Label       matlab.ui.control.Label
        LayerNumberSpinner_2            matlab.ui.control.Spinner
        LayerApplyButton_2              matlab.ui.control.Button
        LayerRemoveButton_2             matlab.ui.control.Button
        ResetButton_2                   matlab.ui.control.Button
        ThicknessmEditFieldLabel_2      matlab.ui.control.Label
        ThicknessEditField_2            matlab.ui.control.EditField
        NumberofLumpedElementsLabel_2   matlab.ui.control.Label
        LumpedElementsField_2           matlab.ui.control.EditField
        ContactConductaceWm2KLabel      matlab.ui.control.Label
        ContactResistanceField_2        matlab.ui.control.EditField
        ThermalConductivityWmKLabel     matlab.ui.control.Label
        ThermalConductivityEditField_2  matlab.ui.control.EditField
        Densitykgm3Label                matlab.ui.control.Label
        DensityEditField_2              matlab.ui.control.EditField
        SpecificHeatCapacityJkgKLabel   matlab.ui.control.Label
        SpecificHeatCapacityEditField_2  matlab.ui.control.EditField
        Costm3Label_3                   matlab.ui.control.Label
        LayerCostEditField_2            matlab.ui.control.EditField
        WallTestTree                    matlab.ui.container.Tree
        WallLayersNode                  matlab.ui.container.TreeNode
        SelectWallTestDataFolderPathButton  matlab.ui.control.Button
        StartWallSimulationButton       matlab.ui.control.Button
        CurrentWallTestStatusTextArea   matlab.ui.control.TextArea
        WallTestCyclePanel              matlab.ui.container.Panel
        NumberofCyclesSpinner_2Label    matlab.ui.control.Label
        NumberofWallCyclesSpinner       matlab.ui.control.Spinner
        FullDurrationhLabel             matlab.ui.control.Label
        WallFullDurrationEditField      matlab.ui.control.NumericEditField
        TimestepsEditFieldLabel_2       matlab.ui.control.Label
        WallTimestepEditField           matlab.ui.control.NumericEditField
        EmptyDurrationhLabel            matlab.ui.control.Label
        WallEmptyDurrationEditField     matlab.ui.control.NumericEditField
        WallTestHeatTransferParametersPanel  matlab.ui.container.Panel
        ParticleInletTemperatureCLabel_2  matlab.ui.control.Label
        ParticleInletTemperature_2      matlab.ui.control.NumericEditField
        AmbientHeatTransferCoefficientWm2KLabel_3  matlab.ui.control.Label
        AmbientHeatTransferCoefficient_2  matlab.ui.control.NumericEditField
        InternalHeatTransferCoefficientWm2KLabel_2  matlab.ui.control.Label
        InternalHeatTransferCoefficient_2  matlab.ui.control.NumericEditField
        AmbientAirTemperatureCLabel_2   matlab.ui.control.Label
        AmbientAirTemperature_2         matlab.ui.control.NumericEditField
        ParticleThermalConductivityWmKLabel_3  matlab.ui.control.Label
        ParticleThermalConductivity_2   matlab.ui.control.NumericEditField
        PackedParticleBulkDensitykgm3Label_3  matlab.ui.control.Label
        ParticleDensity_2               matlab.ui.control.NumericEditField
        ParticleSpecificHeatCapacityJkgKLabel_3  matlab.ui.control.Label
        ParticleHeatCapacity_2          matlab.ui.control.NumericEditField
        BaseTab                         matlab.ui.container.Tab
        RoofTab                         matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
    end

    
    properties (Access = private)
        % simulation parameters
        Bin = FF();             % instance of model class that simulations will be built on        
        % GUI parameters
        WallLayers              % bin wall setup tab tree node storage
        InsWallLayers           % bin wall setup ins tab tree storage
        appWallInsulation       % wall insulation info for ins test
        BaseLayers              % bin base setup tab tree node storage
        RoofLayers              % bin roof setup tab tree node storage
        BinOutlineCoords        % coordinates for bin outline lines
        BinOutlineLines         % lines for bin outline in bin diagram
        WallCoords              % coordinates for wall layer lines
        WallLines               % lines for wall layers in bin diagram
        WallColors              % colors for wall layers
        BaseCoords              % coordinates for base layer lines
        BaseLines               % lines for base layers in bin diagram
        BaseColors              % colors for base layers
        RoofCoords              % coordinates for roof layer lines
        RoofLines               % lines for roof layers in bin diagram
        RoofColors              % colors for roof layers
        % figures
        outletTempFig           % outlet temperature results figure
        bulkTempFig             % bulk volumetric temperature figure
        particleHeatLossFig     % particle domain heat loss figure
        wallTempFig             % wall insulation temperature figure
        wallHeatFluxFig         % wall insulation heat flux figure
        baseTempFig             % wall insulation temperature figure
        baseHeatFluxFig         % wall insulation heat flux figure        
    end
    
    methods (Access = private)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Bin Setup Tab Functions
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function setBinFromFile(app, file)
            app.Bin = load(file, '-mat'); app.Bin = app.Bin.var;
            setGeometryFromFile(app);
            setInsulationFromFile(app);
            setParamsFromFile(app);
            reInitObj(app.Bin);
        end
        function setGeometryFromFile(app)
            % sets the bin geometry with given height and diameter
            if isempty(app.BinHeightEditField.Value), app.BinHeightEditField.Value = 1; end
            if isempty(app.BinDiameterEditField.Value), app.BinDiameterEditField.Value = 0.5; end
            app.BinHeightEditField.Value = app.Bin.Hp;
            app.BinDiameterEditField.Value = 2*app.Bin.bp*app.Bin.Hp;
            % store and plot bin outline on bin diagram 
            if ~isempty(app.BinOutlineLines)
                delete(app.BinOutlineLines{1});
                delete(app.BinOutlineLines{2});
                delete(app.BinOutlineLines{3});
                delete(app.BinOutlineLines{4});
            end     
            app.BinOutlineCoords = {[0, 0], [0, 1];
                                   [app.Bin.bp, app.Bin.bp], [0, 1];
                                   [0, app.Bin.bp], [0, 0];
                                   [0, app.Bin.bp], [1, 1]}; 
            set(app.BinDiagram, 'DataAspectRatio', [1, 1, 1]);
            hold(app.BinDiagram, 'on');
            app.BinOutlineLines{1} = plot(app.BinDiagram, app.BinOutlineCoords{1, 1}, app.BinOutlineCoords{1, 2}, '-.k', 'LineWidth', 1);            
            app.BinOutlineLines{2} = plot(app.BinDiagram, app.BinOutlineCoords{2, 1}, app.BinOutlineCoords{2, 2}, '-k', 'LineWidth', 2);
            app.BinOutlineLines{3} = plot(app.BinDiagram, app.BinOutlineCoords{3, 1}, app.BinOutlineCoords{3, 2}, '-k', 'LineWidth', 2);
            app.BinOutlineLines{4} = plot(app.BinDiagram, app.BinOutlineCoords{4, 1}, app.BinOutlineCoords{4, 2}, '-k', 'LineWidth', 2);
        end
        function setInsulationFromFile(app)
            % set wall layers stored in file
            wallSize = size(app.Bin.wallInsulation, 1);
            for i = 1:wallSize
                % append layer to the end 
                name_ = app.Bin.wallInsulation{i, 1};
                thickness_ = app.Bin.wallInsulation{i, 2}(2) - app.Bin.wallInsulation{i, 2}(1);
                k_ = app.Bin.wallInsulation{i, 3};
                rho_ = app.Bin.wallInsulation{i, 4};
                cp_ = app.Bin.wallInsulation{i, 5};
                elements_ = app.Bin.wallInsulation{i, 6};
                hc_ = app.Bin.wallInsulation{i, 7};
                app.WallColors{i} = 'r';
                app.WallLayers{i} = uitreenode(app.LayersTree.Children(1), 'Text', sprintf('%d: %s', i, name_));
                uitreenode(app.WallLayers{i}, 'Text', sprintf('t = %1.4f m', thickness_));
                uitreenode(app.WallLayers{i}, 'Text', sprintf('k = %1.4f W/mK', k_));
                uitreenode(app.WallLayers{i}, 'Text', sprintf('rho = %1.4f kg/m3', rho_));
                uitreenode(app.WallLayers{i}, 'Text', sprintf('c = %1.4f J/kgK', cp_));
                uitreenode(app.WallLayers{i}, 'Text', sprintf('# of elements = %1.0f', elements_));
                uitreenode(app.WallLayers{i}, 'Text', sprintf('hc = %1.4f', hc_));
                setWallCoordinates(app, i);
                plotWallLayers(app, i);
            end
            % set base layers stored in file
            baseSize = size(app.Bin.baseInsulation, 1);
            for i = 1:baseSize
                % append layer to the end 
                name_ = app.Bin.baseInsulation{i, 1};
                thickness_ = app.Bin.baseInsulation{i, 2}(1) - app.Bin.baseInsulation{i, 2}(2);
                k_ = app.Bin.baseInsulation{i, 3};
                rho_ = app.Bin.baseInsulation{i, 4};
                cp_ = app.Bin.baseInsulation{i, 5}; 
                elements_ = app.Bin.baseInsulation{i, 6};
                hc_ = app.Bin.baseInsulation{i, 7};
                app.BaseColors{i} = 'r';
                app.BaseLayers{i} = uitreenode(app.LayersTree.Children(2), 'Text', sprintf('%d: %s', i, name_));
                uitreenode(app.BaseLayers{i}, 'Text', sprintf('t = %1.4f m', thickness_));
                uitreenode(app.BaseLayers{i}, 'Text', sprintf('k = %1.4f W/mK', k_));
                uitreenode(app.BaseLayers{i}, 'Text', sprintf('rho = %1.4f kg/m3', rho_));
                uitreenode(app.BaseLayers{i}, 'Text', sprintf('c = %1.4f J/kgK', cp_));
                uitreenode(app.BaseLayers{i}, 'Text', sprintf('# of elements = %1.0f', elements_));
                uitreenode(app.BaseLayers{i}, 'Text', sprintf('hc = %1.4f', hc_));
                setBaseCoordinates(app, i);
                plotBaseLayers(app, i);
            end
            % set roof layers stored in file
            roofSize = size(app.Bin.roofInsulation, 1);
            for i = 1:roofSize
                % append layer to the end 
                name_ = app.Bin.roofInsulation{i, 1};
                thickness_ = app.Bin.roofInsulation{i, 2}(2) - app.Bin.roofInsulation{i, 2}(1);
                k_ = app.Bin.roofInsulation{i, 3};
                rho_ = app.Bin.roofInsulation{i, 4};
                cp_ = app.Bin.roofInsulation{i, 5};
%                 elements_ = app.Bin.roofInsulation{i, 6};
%                 hc_ = app.Bin.roofInsulation{i, 7};
                app.RoofColors{i} = 'r';
                app.RoofLayers{i} = uitreenode(app.LayersTree.Children(3), 'Text', sprintf('%d: %s', i, name_));
                uitreenode(app.RoofLayers{i}, 'Text', sprintf('t = %1.4f m', thickness_));
                uitreenode(app.RoofLayers{i}, 'Text', sprintf('k = %1.4f W/mK', k_));
                uitreenode(app.RoofLayers{i}, 'Text', sprintf('rho = %1.4f kg/m3', rho_));
                uitreenode(app.RoofLayers{i}, 'Text', sprintf('c = %1.4f J/kgK', cp_));
%                 uitreenode(app.RoofLayers{i}, 'Text', sprintf('# of elements = %1.0f', elements_));
%                 uitreenode(app.RoofLayers{i}, 'Text', sprintf('hc = %1.4f', hc_));
                setRoofCoordinates(app, i);
                plotRoofLayers(app, i);
            end
        end
        function setParamsFromFile(app)
            app.ParticleThermalConductivity.Value = app.Bin.kp;
            app.ParticleDensity.Value = app.Bin.rhopPack;
            app.ParticleHeatCapacity.Value = app.Bin.cpp;
            app.ParticleDynamicViscosity.Value = app.Bin.nup;
            fillParticleDataTree(app);
            app.ChargeDurrationEditField.Value = app.Bin.chargeDurration;
            app.HoldDurrationEditField.Value = app.Bin.holdDurration;
            app.DischargeDurrationEditField.Value = app.Bin.dischargeDurration;
            app.TimestepEditField.Value = app.Bin.timeStep;
            app.InletMassFlowRateEditField.Value = app.Bin.inletMassFlowRate;
            app.OutletMassFlowRateEditField.Value = app.Bin.outletMassFlowRate;
            fillCycleDataTree(app);
            app.ParticleInletTemperature.Value = app.Bin.T0;
            app.AmbientAirTemperature.Value = app.Bin.Tinf;
            app.InternalAirTemperature.Value = app.Bin.thetaA*(app.Bin.T0 - app.Bin.Tinf) + app.Bin.Tinf;
            app.AmbientHeatTransferCoefficient.Value = app.Bin.hInf;
            app.InternalHeatTransferCoefficient.Value = app.Bin.hp5C;
            app.InternalHeatTransferCoefficient.Value = app.Bin.hp5H;
            app.InternalHeatTransferCoefficient.Value = app.Bin.hp5D;                               
            fillHeatTransferPropertiesDataTree(app);
        end
        function addInternalWallLayer(app, layerNum)
            % organizes layers according to user input
            wallSize = size(app.WallLayers, 2);
            for i = 1:wallSize, app.WallLayers{i}.delete; end
            name = zeros(wallSize + 1, 1);
            thickness = zeros(wallSize + 1, 1);
            elements = zeros(wallSize + 1, 1);
            hc = zeros(wallSize + 1, 1);
            conductivity = zeros(wallSize + 1, 1);
            rho = zeros(wallSize + 1, 1);    
            cp = zeros(wallSize + 1, 1);
            tempColors = cell(wallSize + 1, 1);
            % set layers in GUI tree
            for i = 1:(wallSize + 1)
                if layerNum == i
                    % add layer information with current submission
                    app.WallLayers{i} = uitreenode(app.LayersTree.Children(1), 'Text', sprintf('%d: %s', ...
                    layerNum, app.MaterialNameEditField.Value));
                    uitreenode(app.WallLayers{i}, 'Text', sprintf('t = %1.0f m', app.ThicknessEditField.Value));
                    uitreenode(app.WallLayers{i}, 'Text', sprintf('k = %1.0f W/mK', app.ThermalConductivityEditField.Value));
                    uitreenode(app.WallLayers{i}, 'Text', sprintf('rho = %1.0f kg/m3', app.DensityEditField.Value));
                    uitreenode(app.WallLayers{i}, 'Text', sprintf('c = %1.0f J/kgK', app.SpecificHeatCapacityEditField.Value));
                    uitreenode(app.WallLayers{i}, 'Text', sprintf('# elements = %1.0f', ...
                         app.LumpedElementsField.Value)); 
                    uitreenode(app.WallLayers{i}, 'Text', sprintf('hc = %1.2f W/m2K', app.ContactResistanceField.Value));
                    name(i) = app.MaterialNameEditField.Value;
                    thickness(i) = app.ThicknessEditField.Value;
                    conductivity(i) = app.ThermalConductivityEditField.Value;
                    rho(i) = app.DensityEditField.Value;
                    cp(i) = app.SpecificHeatCapacityEditField.Value;
                    elements(i) = app.LumpedElementsField.Value;
                    hc(i) = app.ContactResistanceField.Value;
                    tempColors{i} = app.ColorDropDown.Value;
                elseif layerNum < i
                    % fill existing layers following new layer with stored information
                    app.WallLayers{i} = uitreenode(app.LayersTree.Children(1), 'Text', sprintf('%d: %s', i, app.Bin.wallInsulation{i-1, 1}));
                    uitreenode(app.WallLayers{i}, 'Text', sprintf('t = %1.0f m', ...
                         app.Bin.wallInsulation{i-1, 2}(2) - app.Bin.wallInsulation{i-1, 2}(1)));
                    uitreenode(app.WallLayers{i}, 'Text', sprintf('k = %1.0f W/mK', app.Bin.wallInsulation{i-1, 3}));
                    uitreenode(app.WallLayers{i}, 'Text', sprintf('rho = %1.0f kg/m3', app.Bin.wallInsulation{i-1, 4}));
                    uitreenode(app.WallLayers{i}, 'Text', sprintf('c = %1.0f J/kgK', app.Bin.wallInsulation{i-1, 5}));
                    uitreenode(app.WallLayers{i}, 'Text', sprintf('# elements = %1.0f', ...
                         app.Bin.wallInsulation{i-1, 6})); 
                    uitreenode(app.WallLayers{i}, 'Text', sprintf('hc = %1.2f W/m2K', app.Bin.wallInsulation{i-1, 7}));
                    name(i) = app.Bin.wallInsulation{i-1, 1};                    
                    thickness(i) = app.Bin.wallInsulation{i-1, 2}(2) - app.Bin.wallInsulation{i-1, 2}(1);
                    conductivity(i) = app.Bin.wallInsulation{i-1, 3};
                    rho(i) = app.Bin.wallInsulation{i-1, 4};
                    cp(i) = app.Bin.wallInsulation{i-1, 5};
                    elements(i) = app.Bin.wallInsulation{i-1, 6};
                    hc(i) = app.Bin.wallInsulation{i-1, 7};
                    tempColors{i} = app.WallColors{i-1};
                else
                    % fill existing layers preceding new layer with stored information
                    app.WallLayers{i} = uitreenode(app.LayersTree.Children(1), 'Text', sprintf('%d: %s', i, app.Bin.wallInsulation{i, 1}));
                    uitreenode(app.WallLayers{i}, 'Text', sprintf('t = %1.0f m', ...
                         app.Bin.wallInsulation{i, 2}(2) - app.Bin.wallInsulation{i, 2}(1)));
                    uitreenode(app.WallLayers{i}, 'Text', sprintf('k = %1.0f W/mK', app.Bin.wallInsulation{i, 3}));
                    uitreenode(app.WallLayers{i}, 'Text', sprintf('rho = %1.0f kg/m3', app.Bin.wallInsulation{i, 4}));
                    uitreenode(app.WallLayers{i}, 'Text', sprintf('c = %1.0f J/kgK', app.Bin.wallInsulation{i, 5}));
                    uitreenode(app.WallLayers{i}, 'Text', sprintf('# elements = %1.0f', ...
                         app.Bin.wallInsulation{i, 6})); 
                    uitreenode(app.WallLayers{i}, 'Text', sprintf('hc = %1.2f W/m2K', app.Bin.wallInsulation{i, 7}));
                    name(i) = app.Bin.wallInsulation{i, 1};                    
                    thickness(i) = app.Bin.wallInsulation{i, 2}(2) - app.Bin.wallInsulation{i, 2}(1);
                    conductivity(i) = app.Bin.wallInsulation{i, 3};
                    rho(i) = app.Bin.wallInsulation{i, 4};
                    cp(i) = app.Bin.wallInsulation{i, 5};
                    elements(i) = app.Bin.wallInsulation{i, 6};
                    hc(i) = app.Bin.wallInsulation{i, 7};
                    tempColors{i} = app.WallColors{i};
                end
            end
            % store layer info
            for i = 1:(wallSize + 1)
                app.Bin.wallInsulation{i, 1} = name(i);
                if i == 1
                    app.Bin.wallInsulation{i, 2} = [app.Bin.bp*app.Bin.Hp, app.Bin.bp*app.Bin.Hp + thickness(1)]; 
                else
                    app.Bin.wallInsulation{i, 2} = [app.Bin.wallInsulation{i - 1, 2}(2), ...
                        app.Bin.wallInsulation{i - 1, 2}(2) + thickness(i)];
                end
                app.Bin.wallInsulation{i, 3} = conductivity(i);
                app.Bin.wallInsulation{i, 4} = rho(i);
                app.Bin.wallInsulation{i, 5} = cp(i);
                app.Bin.wallInsulation{i, 6} = elements(i);
                app.Bin.wallInsulation{i, 7} = hc(i);
            end 
            % update coordinates and lines
            app.WallCoords = {}; app.WallColors = tempColors;
            for i = 1:(wallSize + 1), setWallCoordinates(app, i); end
            redrawWallLines(app);
        end
        
        function addInternalBaseLayer(app, layerNum)
            % organizes base layers according to user input
            baseSize = size(app.BaseLayers, 2);
            for i = 1:baseSize, app.BaseLayers{i}.delete; end
            name = zeros(baseSize + 1, 1);           
            thickness = zeros(baseSize + 1, 1);
            elements = zeros(baseSize + 1, 1);
            hc = zeros(baseSize + 1, 1);
            conductivity = zeros(baseSize + 1, 1);
            rho = zeros(baseSize + 1, 1);    
            cp = zeros(baseSize + 1, 1);
            tempColors = cell(baseSize + 1, 1);
            % set layers in GUI tree
            for i = 1:(baseSize + 1)
                if layerNum == i
                    % add layer information with current submission
                    app.BaseLayers{i} = uitreenode(app.LayersTree.Children(2), 'Text', sprintf('%d: %s', ...
                    layerNum, app.MaterialNameEditField.Value));
                    uitreenode(app.BaseLayers{i}, 'Text', sprintf('t = %1.0f m', app.ThicknessEditField.Value));
                    uitreenode(app.BaseLayers{i}, 'Text', sprintf('k = %1.0f W/mK', app.ThermalConductivityEditField.Value));
                    uitreenode(app.BaseLayers{i}, 'Text', sprintf('rho = %1.0f kg/m3', app.DensityEditField.Value));
                    uitreenode(app.BaseLayers{i}, 'Text', sprintf('c = %1.0f J/kgK', app.SpecificHeatCapacityEditField.Value)); 
                    uitreenode(app.BaseLayers{i}, 'Text', sprintf('# elements = %1.0f', ...
                         app.LumpedElementsField.Value)); 
                    uitreenode(app.BaseLayers{i}, 'Text', sprintf('hc = %1.2f W/m2K', app.ContactResistanceField.Value));
                    name(i) = app.MaterialNameEditField.Value;
                    thickness(i) = app.ThicknessEditField.Value;
                    conductivity(i) = app.ThermalConductivityEditField.Value;
                    rho(i) = app.DensityEditField.Value;
                    cp(i) = app.SpecificHeatCapacityEditField.Value;
                    elements(i) = app.LumpedElementsField.Value;
                    hc(i) = app.ContactResistanceField.Value;
                    tempColors{i} = app.ColorDropDown.Value;
                elseif layerNum < i
                    % fill existing layers following new layer with stored information
                    app.BaseLayers{i} = uitreenode(app.LayersTree.Children(2), 'Text', sprintf('%d: %s', i, app.Bin.baseInsulation{i-1, 1}));
                    uitreenode(app.BaseLayers{i}, 'Text', sprintf('t = %1.0f m', ...
                         app.Bin.baseInsulation{i-1, 2}(2) - app.Bin.baseInsulation{i-1, 2}(1)));
                    uitreenode(app.BaseLayers{i}, 'Text', sprintf('k = %1.0f W/mK', app.Bin.baseInsulation{i-1, 3}));
                    uitreenode(app.BaseLayers{i}, 'Text', sprintf('rho = %1.0f kg/m3', app.Bin.baseInsulation{i-1, 4}));
                    uitreenode(app.BaseLayers{i}, 'Text', sprintf('c = %1.0f J/kgK', app.Bin.baseInsulation{i-1, 5}));
                    uitreenode(app.BaseLayers{i}, 'Text', sprintf('# elements = %1.0f', ...
                         app.Bin.baseInsulation{i-1, 6})); 
                    uitreenode(app.BaseLayers{i}, 'Text', sprintf('hc = %1.2f W/m2K', app.Bin.baseInsulation{i-1, 7}));
                    name(i) = app.Bin.baseInsulation{i-1, 1};                    
                    thickness(i) = app.Bin.baseInsulation{i-1, 2}(2) - app.Bin.baseInsulation{i-1, 2}(1);
                    conductivity(i) = app.Bin.baseInsulation{i-1, 3};
                    rho(i) = app.Bin.baseInsulation{i-1, 4};
                    cp(i) = app.Bin.baseInsulation{i-1, 5};
                    elements(i) = app.Bin.baseInsulation{i-1, 6};
                    hc(i) = app.Bin.baseInsulation{i-1, 7};
                    tempColors{i} = app.BaseColors{i-1};
                else
                    % fill existing layers preceding new layer with stored information
                    app.BaseLayers{i} = uitreenode(app.LayersTree.Children(2), 'Text', sprintf('%d: %s', i, app.Bin.baseInsulation{i, 1}));
                    uitreenode(app.BaseLayers{i}, 'Text', sprintf('t = %1.0f m', ...
                         app.Bin.baseInsulation{i, 2}(2) - app.Bin.baseInsulation{i, 2}(1)));
                    uitreenode(app.BaseLayers{i}, 'Text', sprintf('k = %1.0f W/mK', app.Bin.baseInsulation{i, 3}));
                    uitreenode(app.BaseLayers{i}, 'Text', sprintf('rho = %1.0f kg/m3', app.Bin.baseInsulation{i, 4}));
                    uitreenode(app.BaseLayers{i}, 'Text', sprintf('c = %1.0f J/kgK', app.Bin.baseInsulation{i, 5}));
                    uitreenode(app.BaseLayers{i}, 'Text', sprintf('# elements = %1.0f', ...
                         app.Bin.baseInsulation{i, 6})); 
                    uitreenode(app.BaseLayers{i}, 'Text', sprintf('hc = %1.2f W/m2K', app.Bin.baseInsulation{i, 7}));
                    name(i) = app.Bin.baseInsulation{i, 1};                    
                    thickness(i) = app.Bin.baseInsulation{i, 2}(2) - app.Bin.baseInsulation{i, 2}(1);
                    conductivity(i) = app.Bin.baseInsulation{i, 3};
                    rho(i) = app.Bin.baseInsulation{i, 4};
                    cp(i) = app.Bin.baseInsulation{i, 5};
                    elements(i) = app.Bin.baseInsulation{i, 6};
                    hc(i) = app.Bin.baseInsulation{i, 7};
                    tempColors{i} = app.BaseColors{i};
                end
            end
            % store layer info
            for i = 1:(baseSize + 1)
                app.Bin.baseInsulation{i, 1} = name(i);
                if i == 1
                    app.Bin.baseInsulation{i, 2} = [0, -thickness(1)]; 
                else
                    app.Bin.baseInsulation{i, 2} = [app.Bin.baseInsulation{i - 1, 2}(2), ...
                        app.Bin.baseInsulation{i - 1, 2}(2) - thickness(i)];
                end
                app.Bin.baseInsulation{i, 3} = conductivity(i);
                app.Bin.baseInsulation{i, 4} = rho(i);
                app.Bin.baseInsulation{i, 5} = cp(i);
                app.Bin.baseInsulation{i, 6} = elements(i);
                app.Bin.baseInsulation{i, 7} = hc(i);
            end 
            % update coordinates and lines
            app.BaseCoords = {}; app.BaseColors = tempColors;
            for i = 1:(baseSize + 1), setBaseCoordinates(app, i); end
            redrawBaseLines(app);
        end
        
        function addInternalRoofLayer(app, layerNum)
            % organizes base layers according to user input
            roofSize = size(app.RoofLayers, 2);
            for i = 1:roofSize, app.RoofLayers{i}.delete; end
            name = zeros(roofSize + 1, 1);
            thickness = zeros(roofSize + 1, 1);
%             elements = zeros(roofSize + 1, 1);
%             hc = zeros(roofSize + 1, 1);
            conductivity = zeros(roofSize + 1, 1);
            rho = zeros(roofSize + 1, 1);    
            cp = zeros(roofSize + 1, 1);
            tempColors = cell(roofSize + 1, 1);
            % set layers in GUI tree
            for i = 1:(roofSize + 1)
                if layerNum == i
                    % add layer information with current submission
                    app.RoofLayers{i} = uitreenode(app.LayersTree.Children(3), 'Text', sprintf('%d: %s', ...
                    layerNum, app.MaterialNameEditField.Value));
                    uitreenode(app.RoofLayers{i}, 'Text', sprintf('t = %1.0f m', app.ThicknessEditField.Value));
                    uitreenode(app.RoofLayers{i}, 'Text', sprintf('k = %1.0f W/mK', app.ThermalConductivityEditField.Value));
                    uitreenode(app.RoofLayers{i}, 'Text', sprintf('rho = %1.0f kg/m3', app.DensityEditField.Value));
                    uitreenode(app.RoofLayers{i}, 'Text', sprintf('c = %1.0f J/kgK', app.SpecificHeatCapacityEditField.Value));
%                     uitreenode(app.RoofLayers{i}, 'Text', sprintf('# elements = %1.0f', ...
%                          app.LumpedElementsField.Value)); 
%                     uitreenode(app.RoofLayers{i}, 'Text', sprintf('hc = %1.2f W/m2K', app.ContactResistanceField.Value));
                    name(i) = app.MaterialNameEditField.Value;
                    thickness(i) = app.ThicknessEditField.Value;
                    conductivity(i) = app.ThermalConductivityEditField.Value;
                    rho(i) = app.DensityEditField.Value;
                    cp(i) = app.SpecificHeatCapacityEditField.Value;
%                     elements(i) = app.LumpedElementsField.Value;
%                     hc(i) = app.ContactResistanceField.Value;
                    tempColors{i} = app.ColorDropDown.Value;
                elseif layerNum < i
                    % fill existing layers following new layer with stored information
                    app.RoofLayers{i} = uitreenode(app.LayersTree.Children(3), 'Text', sprintf('%d: %s', i, app.Bin.roofInsulation{i-1, 1}));
                    uitreenode(app.RoofLayers{i}, 'Text', sprintf('t = %1.0f m', ...
                         app.Bin.roofInsulation{i-1, 2}(2) - app.Bin.roofInsulation{i-1, 2}(1)));
                    uitreenode(app.RoofLayers{i}, 'Text', sprintf('k = %1.0f W/mK', app.Bin.roofInsulation{i-1, 3}));
                    uitreenode(app.RoofLayers{i}, 'Text', sprintf('rho = %1.0f kg/m3', app.Bin.roofInsulation{i-1, 4}));
                    uitreenode(app.RoofLayers{i}, 'Text', sprintf('c = %1.0f J/kgK', app.Bin.roofInsulation{i-1, 5}));
%                     uitreenode(app.RoofLayers{i}, 'Text', sprintf('# elements = %1.0f', ...
%                          app.Bin.roofInsulation{i-1, 6})); 
%                     uitreenode(app.RoofLayers{i}, 'Text', sprintf('hc = %1.2f W/m2K', app.Bin.roofInsulation{i-1, 7}));
                    name(i) = app.Bin.roofInsulation{i-1, 1};                    
                    thickness(i) = app.Bin.roofInsulation{i-1, 2}(2) - app.Bin.roofInsulation{i-1, 2}(1);
                    conductivity(i) = app.Bin.roofInsulation{i-1, 3};
                    rho(i) = app.Bin.roofInsulation{i-1, 4};
                    cp(i) = app.Bin.roofInsulation{i-1, 5}; 
%                     elements(i) = app.Bin.roofInsulation{i-1, 6};
%                     hc(i) = app.Bin.roofInsulation{i-1, 7};
                    tempColors{i} = app.RoofColors{i-1};
                else
                    % fill existing layers preceding new layer with stored information
                    app.RoofLayers{i} = uitreenode(app.LayersTree.Children(3), 'Text', sprintf('%d: %s', i, app.Bin.roofInsulation{i, 1}));
                    uitreenode(app.RoofLayers{i}, 'Text', sprintf('t = %1.0f m', ...
                         app.Bin.roofInsulation{i, 2}(2) - app.Bin.roofInsulation{i, 2}(1)));
                    uitreenode(app.RoofLayers{i}, 'Text', sprintf('k = %1.0f W/mK', app.Bin.roofInsulation{i, 3}));
                    uitreenode(app.RoofLayers{i}, 'Text', sprintf('rho = %1.0f kg/m3', app.Bin.roofInsulation{i, 4}));
                    uitreenode(app.RoofLayers{i}, 'Text', sprintf('c = %1.0f J/kgK', app.Bin.roofInsulation{i, 5}));
%                     uitreenode(app.RoofLayers{i}, 'Text', sprintf('# elements = %1.0f', ...
%                          app.Bin.roofInsulation{i, 6})); 
%                     uitreenode(app.RoofLayers{i}, 'Text', sprintf('hc = %1.2f W/m2K', app.Bin.roofInsulation{i, 7}));
                    name(i) = app.Bin.roofInsulation{i, 1};                    
                    thickness(i) = app.Bin.roofInsulation{i, 2}(2) - app.Bin.roofInsulation{i, 2}(1);
                    conductivity(i) = app.Bin.roofInsulation{i, 3};
                    rho(i) = app.Bin.roofInsulation{i, 4};
                    cp(i) = app.Bin.roofInsulation{i, 5};
%                     elements(i) = app.Bin.roofInsulation{i, 6};
%                     hc(i) = app.Bin.roofInsulation{i, 7};
                    tempColors{i} = app.RoofColors{i};
                end
            end
            % store layer info
            for i = 1:(roofSize+1)
                app.Bin.roofInsulation{i, 1} = name(i);
                if i == 1
                    app.Bin.roofInsulation{i, 2} = [1, 1 + thickness(1)]; 
                else
                    app.Bin.roofInsulation{i, 2} = [app.Bin.roofInsulation{i - 1, 2}(2), ...
                        app.Bin.roofInsulation{i - 1, 2}(2) + thickness(i)];
                end
                app.Bin.roofInsulation{i, 3} = conductivity(i);
                app.Bin.roofInsulation{i, 4} = rho(i);
                app.Bin.roofInsulation{i, 5} = cp(i);
%                 app.Bin.roofInsulation{i, 6} = elements(i);
%                 app.Bin.roofInsulation{i, 7} = hc(i);
            end
            % update coordinates and lines
            app.RoofCoords = {}; app.RoofColors = tempColors;
            for i = 1:(roofSize + 1), setRoofCoordinates(app, i); end
            redrawRoofLines(app);
        end
        
        function removeWallLayer(app, layerNum)
            % organizes layers according to user input
            wallSize = size(app.WallLayers, 2);
            for i = 1:wallSize, app.WallLayers{i}.delete; end
            app.WallLayers = {};
            name = zeros(wallSize - 1, 1);
            thickness = zeros(wallSize - 1, 1);
            elements = zeros(wallSize - 1, 1);
            hc = zeros(wallSize - 1, 1);
            conductivity = zeros(wallSize - 1, 1);
            rho = zeros(wallSize - 1, 1);    
            cp = zeros(wallSize - 1, 1);
            % set layers in GUI tree
            for i = 1:wallSize
                if layerNum == i
                    % skip
                elseif i > layerNum
                    % fill existing layers following new layer with stored information
                    app.WallLayers{i-1} = uitreenode(app.LayersTree.Children(1), 'Text', sprintf('%d: %s', i-1, app.Bin.wallInsulation{i, 1}));
                    uitreenode(app.WallLayers{i-1}, 'Text', sprintf('t = %1.0f m', ...
                         app.Bin.wallInsulation{i, 2}(2) - app.Bin.wallInsulation{i, 2}(1)));     
                    uitreenode(app.WallLayers{i-1}, 'Text', sprintf('k = %1.0f W/mK', app.Bin.wallInsulation{i, 3}));
                    uitreenode(app.WallLayers{i-1}, 'Text', sprintf('rho = %1.0f kg/m3', app.Bin.wallInsulation{i, 4}));
                    uitreenode(app.WallLayers{i-1}, 'Text', sprintf('c = %1.0f J/kgK', app.Bin.wallInsulation{i, 5}));
                    uitreenode(app.WallLayers{i-1}, 'Text', sprintf('# elements = %1.0f', ...
                         app.Bin.wallInsulation{i, 6})); 
                    uitreenode(app.WallLayers{i-1}, 'Text', sprintf('hc = %1.2f W/m2K', app.Bin.wallInsulation{i, 7}));
                    name(i-1) = app.Bin.wallInsulation{i, 1};                    
                    thickness(i-1) = app.Bin.wallInsulation{i, 2}(2) - app.Bin.wallInsulation{i, 2}(1);
                    conductivity(i-1) = app.Bin.wallInsulation{i, 3};
                    rho(i-1) = app.Bin.wallInsulation{i, 4};
                    cp(i-1) = app.Bin.wallInsulation{i, 5};
                    elements(i-1) = app.Bin.wallInsulation{i, 6};
                    hc(i-1) = app.Bin.wallInsulation{i, 7};                                      
                else
                    % fill existing layers preceding new layer with stored information
                    app.WallLayers{i} = uitreenode(app.LayersTree.Children(1), 'Text', sprintf('%d: %s', i, app.Bin.wallInsulation{i, 1}));
                    uitreenode(app.WallLayers{i}, 'Text', sprintf('t = %1.0f m', ...
                         app.Bin.wallInsulation{i, 2}(2) - app.Bin.wallInsulation{i, 2}(1)));
                    uitreenode(app.WallLayers{i}, 'Text', sprintf('k = %1.0f W/mK', app.Bin.wallInsulation{i, 3}));
                    uitreenode(app.WallLayers{i}, 'Text', sprintf('rho = %1.0f kg/m3', app.Bin.wallInsulation{i, 4}));
                    uitreenode(app.WallLayers{i}, 'Text', sprintf('c = %1.0f J/kgK', app.Bin.wallInsulation{i, 5}));
                    uitreenode(app.WallLayers{i}, 'Text', sprintf('# elements = %1.0f', ...
                         app.Bin.wallInsulation{i, 6})); 
                    uitreenode(app.WallLayers{i}, 'Text', sprintf('hc = %1.2f W/m2K', app.Bin.wallInsulation{i, 7}));
                    name(i) = app.Bin.wallInsulation{i, 1};                    
                    thickness(i) = app.Bin.wallInsulation{i, 2}(2) - app.Bin.wallInsulation{i, 2}(1);
                    conductivity(i) = app.Bin.wallInsulation{i, 3};
                    rho(i) = app.Bin.wallInsulation{i, 4};
                    cp(i) = app.Bin.wallInsulation{i, 5};
                    elements(i) = app.Bin.wallInsulation{i, 6};
                    hc(i) = app.Bin.wallInsulation{i, 7};
                end
            end
            % store layer info
            app.Bin.wallInsulation = {};
            for i = 1:wallSize-1
                app.Bin.wallInsulation{i, 1} = name(i);
                if i == 1
                    app.Bin.wallInsulation{i, 2} = [app.Bin.bp*app.Bin.Hp, app.Bin.bp*app.Bin.Hp + thickness(1)]; 
                else
                    app.Bin.wallInsulation{i, 2} = [app.Bin.wallInsulation{i-1, 2}(2), ...
                        app.Bin.wallInsulation{i-1, 2}(2) + thickness(i)];
                end
                app.Bin.wallInsulation{i, 3} = conductivity(i);
                app.Bin.wallInsulation{i, 4} = rho(i);
                app.Bin.wallInsulation{i, 5} = cp(i);
                app.Bin.wallInsulation{i, 6} = elements(i);
                app.Bin.wallInsulation{i, 7} = hc(i);
            end 
        end
        
        function removeBaseLayer(app, layerNum)
            % organizes layers according to user input
            baseSize = size(app.BaseLayers, 2);
            for i = 1:baseSize, app.BaseLayers{i}.delete; end
            app.BaseLayers = {};
            name = zeros(baseSize - 1, 1);
            thickness = zeros(baseSize - 1, 1);
            elements = zeros(baseSize - 1, 1);
            hc = zeros(baseSize - 1, 1);
            conductivity = zeros(baseSize - 1, 1);
            rho = zeros(baseSize - 1, 1);    
            cp = zeros(baseSize - 1, 1);
            % set layers in GUI tree
            for i = 1:baseSize
                if layerNum == i
                    % skip
                elseif i > layerNum
                    % fill existing layers following new layer with stored information
                    app.BaseLayers{i-1} = uitreenode(app.LayersTree.Children(2), 'Text', sprintf('%d: %s', i-1, app.Bin.baseInsulation{i, 1}));
                    uitreenode(app.BaseLayers{i-1}, 'Text', sprintf('t = %1.0f m', ...
                         app.Bin.baseInsulation{i, 2}(2) - app.Bin.baseInsulation{i, 2}(1)));
                    uitreenode(app.BaseLayers{i-1}, 'Text', sprintf('k = %1.0f W/mK', app.Bin.baseInsulation{i, 3}));
                    uitreenode(app.BaseLayers{i-1}, 'Text', sprintf('rho = %1.0f kg/m3', app.Bin.baseInsulation{i, 4}));
                    uitreenode(app.BaseLayers{i-1}, 'Text', sprintf('c = %1.0f J/kgK', app.Bin.baseInsulation{i, 5}));
                    uitreenode(app.BaseLayers{i-1}, 'Text', sprintf('# elements = %1.0f', ...
                         app.Bin.baseInsulation{i, 6})); 
                    uitreenode(app.BaseLayers{i-1}, 'Text', sprintf('hc = %1.2f W/m2K', app.Bin.baseInsulation{i, 7}));
                    name(i-1) = app.Bin.baseInsulation{i, 1};                    
                    thickness(i-1) = app.Bin.baseInsulation{i, 2}(2) - app.Bin.baseInsulation{i, 2}(1);
                    conductivity(i-1) = app.Bin.baseInsulation{i, 3};
                    rho(i-1) = app.Bin.baseInsulation{i, 4};
                    cp(i-1) = app.Bin.baseInsulation{i, 5};
                    elements(i-1) = app.Bin.baseInsulation{i, 6};
                    hc(i-1) = app.Bin.baseInsulation{i, 7};
                else
                    % fill existing layers preceding new layer with stored information
                    app.BaseLayers{i} = uitreenode(app.LayersTree.Children(2), 'Text', sprintf('%d: %s', i, app.Bin.baseInsulation{i, 1}));
                    uitreenode(app.BaseLayers{i}, 'Text', sprintf('t = %1.0f m', ...
                         app.Bin.baseInsulation{i, 2}(2) - app.Bin.baseInsulation{i, 2}(1)));
                    uitreenode(app.BaseLayers{i}, 'Text', sprintf('k = %1.0f W/mK', app.Bin.baseInsulation{i, 3}));
                    uitreenode(app.BaseLayers{i}, 'Text', sprintf('rho = %1.0f kg/m3', app.Bin.baseInsulation{i, 4}));
                    uitreenode(app.BaseLayers{i}, 'Text', sprintf('c = %1.0f J/kgK', app.Bin.baseInsulation{i, 5}));
                    uitreenode(app.BaseLayers{i}, 'Text', sprintf('# elements = %1.0f', ...
                         app.Bin.baseInsulation{i, 6})); 
                    uitreenode(app.BaseLayers{i}, 'Text', sprintf('hc = %1.2f W/m2K', app.Bin.baseInsulation{i, 7}));
                    name(i) = app.Bin.baseInsulation{i, 1};                    
                    thickness(i) = app.Bin.baseInsulation{i, 2}(2) - app.Bin.baseInsulation{i, 2}(1);
                    conductivity(i) = app.Bin.baseInsulation{i, 3};
                    rho(i) = app.Bin.baseInsulation{i, 4};
                    cp(i) = app.Bin.baseInsulation{i, 5};
                    elements(i) = app.Bin.baseInsulation{i, 6};
                    hc(i) = app.Bin.baseInsulation{i, 7};
                end
            end
            % store layer info
            app.Bin.baseInsulation = {};
            for i = 1:baseSize-1
                app.Bin.baseInsulation{i, 1} = name(i);
                if i == 1
                    app.Bin.baseInsulation{i, 2} = [0, -thickness(1)]; 
                else
                    app.Bin.baseInsulation{i, 2} = [app.Bin.baseInsulation{i-1, 2}(2), ...
                        app.Bin.baseInsulation{i-1, 2}(2) - thickness(i)];
                end
                app.Bin.baseInsulation{i, 3} = conductivity(i);
                app.Bin.baseInsulation{i, 4} = rho(i);
                app.Bin.baseInsulation{i, 5} = cp(i);
                app.Bin.baseInsulation{i, 6} = elements(i);
                app.Bin.baseInsulation{i, 7} = hc(i);
            end 
        end
        
        function removeRoofLayer(app, layerNum)
            % organizes layers according to user input
            roofSize = size(app.RoofLayers, 2);
            for i = 1:roofSize, app.RoofLayers{i}.delete; end
            app.RoofLayers = {};
            name = zeros(roofSize - 1, 1);
            thickness = zeros(roofSize - 1, 1);
%             elements = zeros(roofSize - 1, 1);
%             hc = zeros(roofSize - 1, 1);
            conductivity = zeros(roofSize - 1, 1);
            rho = zeros(roofSize - 1, 1);    
            cp = zeros(roofSize - 1, 1);
            % set layers in GUI tree
            for i = 1:roofSize
                if layerNum == i
                    % skip
                elseif i > layerNum
                    % fill existing layers following new layer with stored information
                    app.RoofLayers{i-1} = uitreenode(app.LayersTree.Children(3), 'Text', sprintf('%d: %s', i-1, app.Bin.roofInsulation{i, 1}));
                    uitreenode(app.RoofLayers{i-1}, 'Text', sprintf('t = %1.0f m', ...
                         app.Bin.roofInsulation{i, 2}(2) - app.Bin.roofInsulation{i, 2}(1)));
                    uitreenode(app.RoofLayers{i-1}, 'Text', sprintf('k = %1.0f W/mK', app.Bin.roofInsulation{i, 3}));
                    uitreenode(app.RoofLayers{i-1}, 'Text', sprintf('rho = %1.0f kg/m3', app.Bin.roofInsulation{i, 4}));
                    uitreenode(app.RoofLayers{i-1}, 'Text', sprintf('c = %1.0f J/kgK', app.Bin.roofInsulation{i, 5}));
%                     uitreenode(app.RoofLayers{i-1}, 'Text', sprintf('# elements = %1.0f', app.Bin.roofInsulation{i, 6}));
%                     uitreenode(app.RoofLayers{i-1}, 'Text', sprintf('hc = %1.2f W/m2K', app.Bin.roofInsulation{i, 7}));
                    name(i-1) = app.Bin.roofInsulation{i, 1};                    
                    thickness(i-1) = app.Bin.roofInsulation{i, 2}(2) - app.Bin.roofInsulation{i, 2}(1);
                    conductivity(i-1) = app.Bin.roofInsulation{i, 3};
                    rho(i-1) = app.Bin.roofInsulation{i, 4};
                    cp(i-1) = app.Bin.roofInsulation{i, 5};
%                     elements(i-1) = app.Bin.roofInsulation{i, 6};
%                     hc(i-1) = app.Bin.roofInsulation{i, 7};
                else
                    % fill existing layers preceding new layer with stored information
                    app.RoofLayers{i} = uitreenode(app.LayersTree.Children(3), 'Text', sprintf('%d: %s', i, app.Bin.roofInsulation{i, 1}));
                    uitreenode(app.RoofLayers{i}, 'Text', sprintf('t = %1.0f m', ...
                         app.Bin.roofInsulation{i, 2}(2) - app.Bin.roofInsulation{i, 2}(1)));
                    uitreenode(app.RoofLayers{i}, 'Text', sprintf('k = %1.0f W/mK', app.Bin.roofInsulation{i, 3}));
                    uitreenode(app.RoofLayers{i}, 'Text', sprintf('rho = %1.0f kg/m3', app.Bin.roofInsulation{i, 4}));
                    uitreenode(app.RoofLayers{i}, 'Text', sprintf('c = %1.0f J/kgK', app.Bin.roofInsulation{i, 5}));
%                     uitreenode(app.RoofLayers{1}, 'Text', sprintf('# elements = %1.0f', app.Bin.roofInsulation{i, 6}));
%                     uitreenode(app.RoofLayers{1}, 'Text', sprintf('hc = %1.2f W/m2K', app.Bin.roofInsulation{i, 7}));
                    name(i) = app.Bin.roofInsulation{i, 1};                    
                    thickness(i) = app.Bin.roofInsulation{i, 2}(2) - app.Bin.roofInsulation{i, 2}(1);
                    conductivity(i) = app.Bin.roofInsulation{i, 3};
                    rho(i) = app.Bin.roofInsulation{i, 4};
                    cp(i) = app.Bin.roofInsulation{i, 5};
%                     elements(i) = app.Bin.roofInsulation{i, 6};
%                     hc(i) = app.Bin.roofInsulation{i, 7};
                end
            end
            % store layer info
            app.Bin.roofInsulation = {};
            for i = 1:roofSize-1
                app.Bin.roofInsulation{i, 1} = name(i);
                if i == 1
                    app.Bin.roofInsulation{i, 2} = [0, -thickness(1)]; 
                else
                    app.Bin.roofInsulation{i, 2} = [app.Bin.roofInsulation{i-1, 2}(2), ...
                        app.Bin.roofInsulation{i-1, 2}(2) - thickness(i)];
                end
                app.Bin.roofInsulation{i, 3} = conductivity(i);
                app.Bin.roofInsulation{i, 4} = rho(i);
                app.Bin.roofInsulation{i, 5} = cp(i);
%                 app.Bin.roofInsulation{i, 6} = elements(i);
%                 app.Bin.roofInsulation{i, 7} = hc(i);
            end 
        end
        
        function setWallCoordinates(app, layerNum)
            % updates coordinates when a layer is added
            thickness = app.Bin.wallInsulation{layerNum, 2}(2) - app.Bin.wallInsulation{layerNum, 2}(1);
            if layerNum == 1
                app.WallCoords{1} = [app.Bin.bp, 1; app.Bin.bp + thickness/app.Bin.Hp, 1; ...
                                 app.Bin.bp + thickness/app.Bin.Hp, 0; app.Bin.bp, 0];
            else
                app.WallCoords{layerNum} = [app.WallCoords{layerNum-1}(2, 1), 1; app.WallCoords{layerNum-1}(2, 1) + thickness/app.Bin.Hp, 1; ...
                                            app.WallCoords{layerNum-1}(2, 1) + thickness/app.Bin.Hp, 0; app.WallCoords{layerNum-1}(2, 1), 0];
            end           
        end
        
        function setBaseCoordinates(app, layerNum)
            % updates coordinates when a layer is added
            thickness = abs(app.Bin.baseInsulation{layerNum, 2}(2) - app.Bin.baseInsulation{layerNum, 2}(1));
            if layerNum == 1
                app.BaseCoords{1} = [app.Bin.bp, 0; app.Bin.bp, -thickness/app.Bin.Hp; ...
                                 0, -thickness/app.Bin.Hp; 0, 0];
            else
                app.BaseCoords{layerNum} = [app.Bin.bp, app.BaseCoords{layerNum-1}(2, 2); app.Bin.bp, app.BaseCoords{layerNum-1}(2, 2) - thickness/app.Bin.Hp; ...
                                            0, app.BaseCoords{layerNum-1}(2, 2) - thickness/app.Bin.Hp; 0, app.BaseCoords{layerNum-1}(2, 2)];
            end           
        end
        
        function setRoofCoordinates(app, layerNum)
            % updates coordinates when a layer is added
            thickness = app.Bin.roofInsulation{layerNum, 2}(2) - app.Bin.roofInsulation{layerNum, 2}(1);
            if layerNum == 1
                app.RoofCoords{1} = [app.Bin.bp, 1; app.Bin.bp, 1 + thickness/app.Bin.Hp; ...
                                 0, 1 + thickness/app.Bin.Hp; 0, 1];
            else
                app.RoofCoords{layerNum} = [app.Bin.bp, app.RoofCoords{layerNum-1}(2, 2); app.Bin.bp, app.RoofCoords{layerNum-1}(2, 2) + thickness/app.Bin.Hp; ...
                                            0, app.RoofCoords{layerNum-1}(2, 2) + thickness/app.Bin.Hp; 0, app.RoofCoords{layerNum-1}(2, 2)];
            end           
        end
        
        function removeWallCoordinates(app, layerNum)
            % updates coordinates when a layer is removed
            wallSize = size(app.WallLayers, 2);
            tempCoords = cell(wallSize, 1);
            tempColors = cell(wallSize, 1);
            for i = 1:wallSize+1
                if i == layerNum
                    % skip
                elseif i < layerNum
                    tempCoords{i} = app.WallCoords{i};
                    tempColors{i} = app.WallColors{i};
                else
                    thickness = (app.WallCoords{i}(2, 1) - app.WallCoords{i}(1, 1))*app.Bin.Hp;
                    tempCoords{i-1} = [app.WallCoords{i-2}(2, 1), 1; app.WallCoords{i-2}(2, 1) + thickness/app.Bin.Hp, 1; ...
                                       app.WallCoords{i-2}(2, 1) + thickness/app.Bin.Hp, 0; app.WallCoords{i-2}(2, 1), 0];
                    tempColors{i-1} = app.WallColors{i};
                end
            end
            app.WallCoords = tempCoords; 
            app.WallColors = tempColors;
            redrawWallLines(app);
        end
        
        function removeBaseCoordinates(app, layerNum)
            % updates coordinates when a layer is removed
            baseSize = size(app.BaseLayers, 2);
            tempCoords = cell(baseSize, 1);
            tempColors = cell(baseSize, 1);
            for i = 1:baseSize+1
                if i == layerNum
                    % skip
                elseif i < layerNum
                    tempCoords{i} = app.BaseCoords{i};
                    tempColors{i} = app.BaseColors{i};
                else
                    thickness = abs(app.BaseCoords{i}(2, 2) - app.BaseCoords{i}(1, 2))*app.Bin.Hp;
                    tempCoords{i-1} = [app.Bin.bp, app.BaseCoords{i-2}(2, 2); app.Bin.bp, app.BaseCoords{i-2}(2, 2) - thickness/app.Bin.Hp; ...
                                            0, app.BaseCoords{i-2}(2, 2) - thickness/app.Bin.Hp; 0, app.BaseCoords{i-2}(2, 2)];
                    tempColors{i-1} = app.BaseColors{i}; 
                end
            end
            app.BaseCoords = tempCoords; 
            app.BaseColors = tempColors;
            redrawBaseLines(app);
        end
        
        function removeRoofCoordinates(app, layerNum)
            % updates coordinates when a layer is removed
            roofSize = size(app.RoofLayers, 2);
            tempCoords = cell(roofSize, 1);
            tempColors = cell(roofSize, 1);
            for i = 1:roofSize+1
                if i == layerNum
                    % skip
                elseif i < layerNum
                    tempCoords{i} = app.RoofCoords{i};
                    tempColors{i} = app.RoofColors{i};
                else
                    thickness = abs(app.RoofCoords{i}(2, 2) - app.RoofCoords{i}(1, 2))*app.Bin.Hp;
                    tempCoords{i-1} = [app.Bin.bp, app.RoofCoords{i-2}(2, 2); app.Bin.bp, app.RoofCoords{i-2}(2, 2) + thickness/app.Bin.Hp; ...
                                            0, app.RoofCoords{i-2}(2, 2) + thickness/app.Bin.Hp; 0, app.RoofCoords{i-2}(2, 2)];
                    tempColors{i-1} = app.RoofColors{i};
                end
            end
            app.RoofCoords = tempCoords; 
            app.RoofColors = tempColors;
            redrawRoofLines(app);
        end
        
        function redrawWallLines(app)
            % removes coresponding lines when a layer is removed
            wallSize = size(app.WallLayers, 2);
            for i = 1:size(app.WallLines, 2), delete(app.WallLines{i}(:)); end
            app.WallLines = {};
            for i = 1:wallSize, plotWallLayers(app, i); end            
        end
        
        function redrawBaseLines(app)
            % removes coresponding lines when a layer is removed
            baseSize = size(app.BaseLayers, 2);
            for i = 1:size(app.BaseLines, 2), delete(app.BaseLines{i}(:)); end
            app.BaseLines = {};
            for i = 1:baseSize, plotBaseLayers(app, i); end            
        end
        
        function redrawRoofLines(app)
            % removes coresponding lines when a layer is removed
            roofSize = size(app.RoofLayers, 2);
            for i = 1:size(app.RoofLines, 2), delete(app.RoofLines{i}(:)); end
            app.BaseLines = {};
            for i = 1:roofSize, plotRoofLayers(app, i); end             
        end
        
        function plotWallLayers(app, layerNum)
            % plots layers from the stored coordinates
            app.WallLines{layerNum}(1) = plot(app.BinDiagram, [app.WallCoords{layerNum}(1, 1), app.WallCoords{layerNum}(2, 1)], ...
                                              [app.WallCoords{layerNum}(1, 2), app.WallCoords{layerNum}(2, 2)], app.WallColors{layerNum}, 'LineWidth', 1); 
            app.WallLines{layerNum}(2) = plot(app.BinDiagram, [app.WallCoords{layerNum}(2, 1), app.WallCoords{layerNum}(3, 1)], ...
                                              [app.WallCoords{layerNum}(2, 2), app.WallCoords{layerNum}(3, 2)], app.WallColors{layerNum}, 'LineWidth', 1);
            app.WallLines{layerNum}(3) = plot(app.BinDiagram, [app.WallCoords{layerNum}(3, 1), app.WallCoords{layerNum}(4, 1)], ...
                                              [app.WallCoords{layerNum}(3, 2), app.WallCoords{layerNum}(4, 2)], app.WallColors{layerNum}, 'LineWidth', 1);
        end
        
        function plotBaseLayers(app, layerNum)
            % plots layers from the stored coordinates
            app.BaseLines{layerNum}(1) = plot(app.BinDiagram, [app.BaseCoords{layerNum}(1, 1), app.BaseCoords{layerNum}(2, 1)], ...
                                              [app.BaseCoords{layerNum}(1, 2), app.BaseCoords{layerNum}(2, 2)], app.BaseColors{layerNum}, 'LineWidth', 1); 
            app.BaseLines{layerNum}(2) = plot(app.BinDiagram, [app.BaseCoords{layerNum}(2, 1), app.BaseCoords{layerNum}(3, 1)], ...
                                              [app.BaseCoords{layerNum}(2, 2), app.BaseCoords{layerNum}(3, 2)], app.BaseColors{layerNum}, 'LineWidth', 1);
            app.BaseLines{layerNum}(3) = plot(app.BinDiagram, [app.BaseCoords{layerNum}(3, 1), app.BaseCoords{layerNum}(4, 1)], ...
                                              [app.BaseCoords{layerNum}(3, 2), app.BaseCoords{layerNum}(4, 2)], app.BaseColors{layerNum}, 'LineWidth', 1);
        end
        
        function plotRoofLayers(app, layerNum)
            % plots layers from the stored coordinates
            app.RoofLines{layerNum}(1) = plot(app.BinDiagram, [app.RoofCoords{layerNum}(1, 1), app.RoofCoords{layerNum}(2, 1)], ...
                                              [app.RoofCoords{layerNum}(1, 2), app.RoofCoords{layerNum}(2, 2)], app.RoofColors{layerNum}, 'LineWidth', 1); 
            app.RoofLines{layerNum}(2) = plot(app.BinDiagram, [app.RoofCoords{layerNum}(2, 1), app.RoofCoords{layerNum}(3, 1)], ...
                                              [app.RoofCoords{layerNum}(2, 2), app.RoofCoords{layerNum}(3, 2)], app.RoofColors{layerNum}, 'LineWidth', 1);
            app.RoofLines{layerNum}(3) = plot(app.BinDiagram, [app.RoofCoords{layerNum}(3, 1), app.RoofCoords{layerNum}(4, 1)], ...
                                              [app.RoofCoords{layerNum}(3, 2), app.RoofCoords{layerNum}(4, 2)], app.RoofColors{layerNum}, 'LineWidth', 1);
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Simulation Setup Tab Functions
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function fillParticleDataTree(app)
            % revises/fills particle data tree
            app.ParticleDataTree.Children.delete;
            uitreenode(app.ParticleDataTree, 'Text', sprintf('Particle Thermal Conductivity ---- %1.4f W/mK', app.Bin.kp))
            uitreenode(app.ParticleDataTree, 'Text', sprintf('Particle Density ---- %1.4f kg/m3', app.Bin.rhopPack))
            uitreenode(app.ParticleDataTree, 'Text', sprintf('Particle Heat Capacity ---- %1.4f J/kgK', app.Bin.cpp))
            uitreenode(app.ParticleDataTree, 'Text', sprintf('Particle Thermal Diffusivity ---- %1.4f m2/s', app.Bin.alphapPacked))
            uitreenode(app.ParticleDataTree, 'Text', sprintf('Particle Kinematic Viscosity ---- %1.4f m2/s', app.Bin.nup))
        end
        
        function fillCycleDataTree(app)
            % revises/fills cycle data tree
            app.StorageCycleDataTree.Children.delete;
            uitreenode(app.StorageCycleDataTree, 'Text', sprintf('Charge Durration ---- %1.4f h', app.ChargeDurrationEditField.Value));
            uitreenode(app.StorageCycleDataTree, 'Text', sprintf('Hold Durration ---- %1.4f h', app.HoldDurrationEditField.Value));
            uitreenode(app.StorageCycleDataTree, 'Text', sprintf('Discharge Durration ---- %1.4f h', app.DischargeDurrationEditField.Value));
            uitreenode(app.StorageCycleDataTree, 'Text', sprintf('Number of Cycles ---- %d', app.NumberofCyclesSpinner.Value));
            uitreenode(app.StorageCycleDataTree, 'Text', sprintf('Timestep ---- %1.4f s', app.TimestepEditField.Value));
            uitreenode(app.StorageCycleDataTree, 'Text', sprintf('Inlet Mass Flow Rate ---- %1.4f kg/s', app.InletMassFlowRateEditField.Value));
            uitreenode(app.StorageCycleDataTree, 'Text', sprintf('Outlet Mass Flow Rate ---- %1.4f kg/s', app.OutletMassFlowRateEditField.Value));
        end
        
        function fillHeatTransferPropertiesDataTree(app)
            % revises/fills heat transfer properties data tree
            app.HeatTransferDataTree.Children.delete;
            uitreenode(app.HeatTransferDataTree, 'Text', sprintf('Particle Inlet Temperature ---- %1.4f °C', app.ParticleInletTemperature.Value));
            uitreenode(app.HeatTransferDataTree, 'Text', sprintf('Internal Air Temperature ---- %1.4f °C', app.InternalAirTemperature.Value));
            uitreenode(app.HeatTransferDataTree, 'Text', sprintf('Ambient Air Temperature ---- %1.4f °C', app.AmbientAirTemperature.Value));
            uitreenode(app.HeatTransferDataTree, 'Text', sprintf('Ambient Heat Transfer Coefficient ---- %1.4f W/m2K', app.AmbientHeatTransferCoefficient.Value));
            uitreenode(app.HeatTransferDataTree, 'Text', sprintf('Internal Heat Transfer Coefficient ---- %1.4f W/m2K', app.InternalHeatTransferCoefficient.Value));
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Simulation Start Up and Run Functions
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function tuneSimilarityParams(app)
            % sets model simulation parameters to satisfy dynamic and
            % geometric scaling
            app.Bin.H = 0.05;
            app.Bin.b = app.Bin.bp;
            % Reynolds number for momentum scaling (finding optimal viscosity)
            app.Bin.nu = app.Bin.nup;
            app.Bin.Uinf = app.Bin.nu*app.Bin.Rep/app.Bin.H;
            % Prandlt number for energy scaling (finding optimal thermal diffusivity)
            app.Bin.alphaPacked = app.Bin.nu/app.Bin.Prp;
            % thermal conductivity is relatively unaffected by particle selection at
            % high temperatures, so keep equivalent for model and prototype
            app.Bin.k = app.Bin.kp;
            app.Bin.cp = app.Bin.cpp;            
            % reset model flow rates
            app.Bin.rhoPack = app.Bin.k/(app.Bin.alphaPacked*app.Bin.cp);
            app.Bin.Q = app.Bin.Uinf*pi*(app.Bin.a0*app.Bin.H)^2;
            app.Bin.QCh = app.Bin.QChp/app.Bin.Qp*app.Bin.Q;           
            % Biot numbers for boundary condition scaling
            app.Bin.h1 = app.Bin.Bip1*app.Bin.k/app.Bin.H;
            app.Bin.h2 = app.Bin.Bip2*app.Bin.k/app.Bin.H;
            app.Bin.h3 = app.Bin.Bip3*app.Bin.k/app.Bin.H;
            app.Bin.h4 = app.Bin.Bip4*app.Bin.k/app.Bin.H;
            app.Bin.h5D = app.Bin.Bip5D*app.Bin.k/app.Bin.H;
            app.Bin.h5H = app.Bin.Bip5H*app.Bin.k/app.Bin.H;
            app.Bin.h5C = app.Bin.Bip5C*app.Bin.k/app.Bin.H;
            % reinitialize all other dependencies
            reInitObj(app.Bin);
        end
        
        function setEmbeddedParams(app)
            % sets hard-coded parameters that are used in the simulation
            % meshing parameters
            app.Bin.h = 0.0011;
            app.Bin.a = 0.044;
            app.Bin.a0 = 0.044;
            app.Bin.nrbar = 600;
            app.Bin.nzbar = 1000;
            app.Bin.nrH = 600;
            app.Bin.nzH = 2000;
            app.Bin.drhat = 0.002;
            app.Bin.drtop = 0.005;
            app.Bin.dzhat = 0.0001;
            app.Bin.dzc = 0.005;
            app.Bin.dzmc = 0.002;
            % fourier summation parameters
            app.Bin.clim = 1e-7;            % only fourier coefficients > clim are used
            app.Bin.p = 4000;               % total number of beta values computed
            app.Bin.pf = 2500;              % range for beta values to be computed
            app.Bin.q = 4000;               % total number of eta values computed
            app.Bin.qf = 2500;              % range for eta values to be computed
            app.Bin.ni = 30;                % number of beta values used in computation of Cnm
            app.Bin.mi = 40;                % number of eta values used in computation of Cnm
            app.Bin.climFD = 5e-7;          % only fourier coefficients > clim are used
            app.Bin.pFD = 4000;             % total number of beta values computed
            app.Bin.pfFD = 2500;            % range for beta values to be computed
            app.Bin.qFD = 4000;             % total number of eta values computed
            app.Bin.qfFD = 2500;            % range for eta values to be computed
            app.Bin.niFD = 30;              % number of beta values used in computation of Cnm
            app.Bin.miFD = 30;              % number of eta values used in computation of Cnm            
            app.Bin.climH = 1e-6;           % only fourier coefficients > clim are used
            app.Bin.pH = 4000;              % total number of beta values computed
            app.Bin.pfH = 2500;             % range for beta values to be computed
            app.Bin.qH = 2500;              % total number of eta values computed
            app.Bin.qfH = 1000;             % range for eta values to be computed
            app.Bin.niH = 25;               % number of beta values used in computation of Cnm
            app.Bin.miH = 20;               % number of eta values used in computation of Cnm
            app.Bin.climFH = 5e-7;          % only fourier coefficients > clim are used
            app.Bin.pFH = 1000;             % total number of beta values computed
            app.Bin.pfFH = 100;             % range for beta values to be computed
            app.Bin.niFH = 25;              % number of beta values used in computation of Cnm
            % embedded heat transfer coefficients
            app.Bin.hInf = 100;
            app.Bin.hcw = 1;                % (W/m2-K) contact resistance coefficient
            app.Bin.hcwA = 0;               % (W/m2-K) contact resistance coefficient
            app.Bin.hp1 = 5;
            app.Bin.hp3 = 4;
            app.Bin.hp5D = 0.2;
            app.Bin.hp5H = 0.2;
            app.Bin.hp5C = 0.1;
            % other
            app.Bin.ls = 50;                % number of time step structures to store in each file
            app.Bin.deltaM = 0.02;                 % mixing depth for charging mode
            app.Bin.thetaI = 0.9;
            app.Bin.thetaA = 0.75;
            % reinitialize all other dependencies
            reInitObj(app.Bin);
        end
                        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Data Visualization and Export Functions
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                        
        
        function saveOutletTempData(app, type, filename)
            % saves outlet temp data to an excel file
            if strcmp(type, 'bulk')
                time_h = app.Bin.thetaOB(:, 1)/3600;
                T_degC = theta2T(app.Bin, app.Bin.thetaOB(:, 2));
                tbl = table(time_h, T_degC);
                filename = strcat(filename, '_bulk', '.xlsx');
            else
                time_h = app.Bin.thetaO(:, 1)/3600;
                T_degC = theta2T(app.Bin, app.Bin.thetaO(:, 2));
                tbl = table(time_h, T_degC);
                filename = strcat(filename, '_cl', '.xlsx');
            end
            writetable(tbl, filename);
        end
        
        function saveHeatLossData(app, filename, qWall_kW, qBase_kW, qTop_kW, qTot_kW, energyIn, energyLoss, percentLoss)
            % saves the selected particle heat loss data
            time_h = app.Bin.Fo2t(app.Bin.Fo(2:end)', 1)/3600;
            tbl = table(time_h, qWall_kW, qBase_kW, qTop_kW, qTot_kW, energyIn, energyLoss, percentLoss);
            writetable(tbl, strcat(filename, '.xlsx'));
        end
        
        function saveInsulationData(app, filename, wallTemp_degC, baseTemp_degC, wallFlux, baseFlux)
            time_h = app.Bin.Fo2t(app.Bin.Fo(2:end)', 1)/3600;
            wallTemp_tbl = table(time_h, wallTemp_degC(2:end, :));
            wallTemp_filename = strcat(filename, '_wallTemp', '.xlsx');                                 
            baseTemp_tbl = table(time_h, baseTemp_degC(2:end, :));
            baseTemp_filename = strcat(filename, 'baseTemp_', '.xlsx');
            wallFlux_tbl = table(time_h, wallFlux(2:end, :));
            wallFlux_filename = strcat(filename, 'wallFlux_', '.xlsx');
            baseFlux_tbl = table(time_h, baseFlux(2:end, :));
            baseFlux_filename = strcat(filename, 'baseFlux_', '.xlsx');
            writetable(wallTemp_tbl, wallTemp_filename);
            writetable(baseTemp_tbl, baseTemp_filename);
            writetable(wallFlux_tbl, wallFlux_filename);
            writetable(baseFlux_tbl, baseFlux_filename);
        end
        
        function saveBulkTempData(app, filename, bulkTemp)
            % saves the temporal bulk temperature data
            time_h = app.Bin.Fo2t(app.Bin.Fo(1:end)', 1)/3600;
            bulkTemp_degC = bulkTemp;
            tbl = table(time_h, bulkTemp_degC);
            writetable(tbl, strcat(filename, '.xlsx'));             
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LayerApplyButton
        function LayerApplyButtonPushed(app, event)
            % Create Node and subnodes with descriptive info
            if app.WallCheckBox.Value
                wallSize = size(app.WallLayers, 2);                
                layerNum = app.LayerNumberSpinner.Value;                
                if layerNum <= wallSize
                    % add and rearange layers
                    addInternalWallLayer(app, layerNum);
                else
                    % append layer to the end  
                    app.WallColors{layerNum} = app.ColorDropDown.Value;
                    app.WallLayers{layerNum} = uitreenode(app.LayersTree.Children(1), 'Text', sprintf('%d: %s', layerNum, app.MaterialNameEditField.Value));
                    uitreenode(app.WallLayers{layerNum}, 'Text', sprintf('t = %1.4f m', app.ThicknessEditField.Value));
                    uitreenode(app.WallLayers{layerNum}, 'Text', sprintf('k = %1.4f W/mK', app.ThermalConductivityEditField.Value));
                    uitreenode(app.WallLayers{layerNum}, 'Text', sprintf('rho = %1.4f kg/m3', app.DensityEditField.Value));
                    uitreenode(app.WallLayers{layerNum}, 'Text', sprintf('c = %1.4f J/kgK', app.SpecificHeatCapacityEditField.Value));
                    uitreenode(app.WallLayers{layerNum}, 'Text', sprintf('# elements = %1.0f', ...
                         app.LumpedElementsField.Value)); 
                    uitreenode(app.WallLayers{layerNum}, 'Text', sprintf('hc = %1.2f W/m2K', app.ContactResistanceField.Value));
                    app.Bin.wallInsulation{layerNum, 1} = app.MaterialNameEditField.Value;
                    if layerNum == 1
                        app.Bin.wallInsulation{layerNum, 2} = [app.Bin.bp*app.Bin.Hp, app.Bin.bp*app.Bin.Hp + app.ThicknessEditField.Value];
                    else
                        app.Bin.wallInsulation{layerNum, 2} = [app.Bin.wallInsulation{layerNum - 1, 2}(2), ...
                            app.Bin.wallInsulation{layerNum - 1, 2}(2) + app.ThicknessEditField.Value];
                    end
                    app.Bin.wallInsulation{layerNum, 3} = app.ThermalConductivityEditField.Value;
                    app.Bin.wallInsulation{layerNum, 4} = app.DensityEditField.Value;
                    app.Bin.wallInsulation{layerNum, 5} = app.SpecificHeatCapacityEditField.Value;
                    app.Bin.wallInsulation{layerNum, 6} = app.LumpedElementsField.Value;
                    app.Bin.wallInsulation{layerNum, 7} = app.ContactResistanceField.Value;
                end
                setWallCoordinates(app, layerNum);
                plotWallLayers(app, layerNum);
            end
            if app.BaseCheckBox.Value
                baseSize = size(app.BaseLayers, 2);
                layerNum = app.LayerNumberSpinner.Value;                
                if layerNum <= baseSize
                    % add and rearange layers
                    addInternalBaseLayer(app, layerNum);
                else
                    % append layer to the end
                    app.BaseColors{layerNum} = app.ColorDropDown.Value;
                    app.BaseLayers{layerNum} = uitreenode(app.LayersTree.Children(2), 'Text', sprintf('%d: %s', layerNum, app.MaterialNameEditField.Value));
                    uitreenode(app.BaseLayers{layerNum}, 'Text', sprintf('t = %1.4f m', app.ThicknessEditField.Value));
                    uitreenode(app.BaseLayers{layerNum}, 'Text', sprintf('k = %1.4f W/mK', app.ThermalConductivityEditField.Value));
                    uitreenode(app.BaseLayers{layerNum}, 'Text', sprintf('rho = %1.4f kg/m3', app.DensityEditField.Value));
                    uitreenode(app.BaseLayers{layerNum}, 'Text', sprintf('c = %1.4f J/kgK', app.SpecificHeatCapacityEditField.Value));
                    uitreenode(app.BaseLayers{layerNum}, 'Text', sprintf('# elements = %1.0f', ...
                         app.LumpedElementsField.Value)); 
                    uitreenode(app.BaseLayers{layerNum}, 'Text', sprintf('hc = %1.2f W/m2K', app.ContactResistanceField.Value));
                    app.Bin.baseInsulation{layerNum, 1} = app.MaterialNameEditField.Value;
                    if layerNum == 1
                        app.Bin.baseInsulation{layerNum, 2} = [0, -app.ThicknessEditField.Value];
                    else
                        app.Bin.baseInsulation{layerNum, 2} = [app.Bin.baseInsulation{layerNum - 1, 2}(2), ...
                            app.Bin.baseInsulation{layerNum - 1, 2}(2) - app.ThicknessEditField.Value];
                    end
                    app.Bin.baseInsulation{layerNum, 3} = app.ThermalConductivityEditField.Value;
                    app.Bin.baseInsulation{layerNum, 4} = app.DensityEditField.Value;
                    app.Bin.baseInsulation{layerNum, 5} = app.SpecificHeatCapacityEditField.Value; 
                    app.Bin.baseInsulation{layerNum, 6} = app.LumpedElementsField.Value;
                    app.Bin.baseInsulation{layerNum, 7} = app.ContactResistanceField.Value;
                end
                setBaseCoordinates(app, layerNum);
                plotBaseLayers(app, layerNum);
            end
            if app.RoofCheckBox.Value
                roofSize = size(app.RoofLayers, 2);
                layerNum = app.LayerNumberSpinner.Value;
                if layerNum <= roofSize
                    % add and rearange layers
                    addInternalRoofLayer(app, layerNum);
                else
                    % append layer to the end
                    app.RoofColors{layerNum} = app.ColorDropDown.Value;
                    app.RoofLayers{layerNum} = uitreenode(app.LayersTree.Children(3), 'Text', sprintf('%d: %s', layerNum, app.MaterialNameEditField.Value));
                    uitreenode(app.RoofLayers{layerNum}, 'Text', sprintf('t = %1.4f m', app.ThicknessEditField.Value));
                    uitreenode(app.RoofLayers{layerNum}, 'Text', sprintf('k = %1.4f W/mK', app.ThermalConductivityEditField.Value));
                    uitreenode(app.RoofLayers{layerNum}, 'Text', sprintf('rho = %1.4f kg/m3', app.DensityEditField.Value));
                    uitreenode(app.RoofLayers{layerNum}, 'Text', sprintf('c = %1.4f J/kgK', app.SpecificHeatCapacityEditField.Value));
%                     uitreenode(app.RoofLayers{layerNum}, 'Text', sprintf('# elements = %1.0f', ...
%                          app.LumpedElementsField.Value)); 
%                     uitreenode(app.RoofLayers{layerNum}, 'Text', sprintf('hc = %1.2f W/m2K', app.ContactResistanceField.Value));
                    app.Bin.roofInsulation{layerNum, 1} = app.MaterialNameEditField.Value;
                    if layerNum == 1
                        app.Bin.roofInsulation{layerNum, 2} = [1, 1 + app.ThicknessEditField.Value];
                    else
                        app.Bin.roofInsulation{layerNum, 2} = [app.Bin.roofInsulation{layerNum - 1, 2}(2), ...
                            app.Bin.roofInsulation{layerNum - 1, 2}(2) + app.ThicknessEditField.Value];
                    end
                    app.Bin.roofInsulation{layerNum, 3} = app.ThermalConductivityEditField.Value;
                    app.Bin.roofInsulation{layerNum, 4} = app.DensityEditField.Value;
                    app.Bin.roofInsulation{layerNum, 5} = app.SpecificHeatCapacityEditField.Value;
%                     app.Bin.roofInsulation{layerNum, 6} = app.LumpedElementsField.Value;
%                     app.Bin.roofInsulation{layerNum, 7} = app.ContactResistanceField.Value;
                end
                setRoofCoordinates(app, layerNum);
                plotRoofLayers(app, layerNum);
            end
        end

        % Button pushed function: GeometrySetButton
        function GeometrySetButtonPushed(app, event)
            % sets the bin geometry with given height and diameter
            if isempty(app.BinHeightEditField.Value), app.BinHeightEditField.Value = 1; end
            if isempty(app.BinDiameterEditField.Value), app.BinDiameterEditField.Value = 0.5; end
            app.Bin.Hp = app.BinHeightEditField.Value;
            app.Bin.bp = 0.5*app.BinDiameterEditField.Value/app.Bin.Hp;
            % store and plot bin outline on bin diagram 
            if ~isempty(app.BinOutlineLines)
                delete(app.BinOutlineLines{1});
                delete(app.BinOutlineLines{2});
                delete(app.BinOutlineLines{3});
                delete(app.BinOutlineLines{4});
            end     
            app.BinOutlineCoords = {[0, 0], [0, 1];
                                   [app.Bin.bp, app.Bin.bp], [0, 1];
                                   [0, app.Bin.bp], [0, 0];
                                   [0, app.Bin.bp], [1, 1]}; 
            set(app.BinDiagram, 'DataAspectRatio', [1, 1, 1]);
            hold(app.BinDiagram, 'on');
            app.BinOutlineLines{1} = plot(app.BinDiagram, app.BinOutlineCoords{1, 1}, app.BinOutlineCoords{1, 2}, '-.k', 'LineWidth', 1);            
            app.BinOutlineLines{2} = plot(app.BinDiagram, app.BinOutlineCoords{2, 1}, app.BinOutlineCoords{2, 2}, '-k', 'LineWidth', 2);
            app.BinOutlineLines{3} = plot(app.BinDiagram, app.BinOutlineCoords{3, 1}, app.BinOutlineCoords{3, 2}, '-k', 'LineWidth', 2);
            app.BinOutlineLines{4} = plot(app.BinDiagram, app.BinOutlineCoords{4, 1}, app.BinOutlineCoords{4, 2}, '-k', 'LineWidth', 2);            
        end

        % Button pushed function: LayerRemoveButton
        function LayerRemoveButtonPushed(app, event)
            l = app.LayerNumberSpinner.Value;
            if app.WallCheckBox.Value
                removeWallLayer(app, l);
                removeWallCoordinates(app, l);
            end
            if app.BaseCheckBox.Value
                removeBaseLayer(app, l);
                removeBaseCoordinates(app, l);
            end
            if app.RoofCheckBox.Value
                removeRoofLayer(app, l);
                removeRoofCoordinates(app, l);
            end
        end

        % Button pushed function: ParticleSetButton
        function ParticleSetButtonPushed(app, event)
            app.Bin.kp = app.ParticleThermalConductivity.Value;
            app.Bin.rhopPack = app.ParticleDensity.Value;
            app.Bin.cpp = app.ParticleHeatCapacity.Value;
            app.Bin.nup = app.ParticleDynamicViscosity.Value;
            fillParticleDataTree(app);            
        end

        % Button pushed function: CycleSetButton
        function CycleSetButtonPushed(app, event)
            app.Bin.Qp = app.OutletMassFlowRateEditField.Value/app.Bin.rhopPack;
            app.Bin.QChp = app.InletMassFlowRateEditField.Value/app.Bin.rhopPack;
            app.Bin.df = app.Bin.t2Fo(app.TimestepEditField.Value, 1);
            app.Bin.FoEnd = app.Bin.t2Fo((app.ChargeDurrationEditField.Value + app.HoldDurrationEditField.Value + ...
                                          app.DischargeDurrationEditField.Value)*app.NumberofCyclesSpinner.Value*3600, 1);
            app.Bin.chargeDurration = app.ChargeDurrationEditField.Value;
            app.Bin.holdDurration = app.HoldDurrationEditField.Value;
            app.Bin.dischargeDurration = app.DischargeDurrationEditField.Value;
            app.Bin.timeStep = app.TimestepEditField.Value;
            app.Bin.inletMassFlowRate = app.InletMassFlowRateEditField.Value;
            app.Bin.outletMassFlowRate = app.OutletMassFlowRateEditField.Value;
            app.Bin.reInitObj;
            app.Bin.FoMode = cell(1, length(app.Bin.Fo));
            app.Bin.FoMode(:) = {'H'};
            if app.ChargeDurrationEditField.Value == 0  
                app.Bin.ztop = 0.9;
            else
                app.Bin.ztop = 0.1;
            end
            nc = ceil(app.ChargeDurrationEditField.Value*3600/app.TimestepEditField.Value);
            nh = ceil(app.HoldDurrationEditField.Value*3600/app.TimestepEditField.Value);
            nd = ceil(app.DischargeDurrationEditField.Value*3600/app.TimestepEditField.Value);
            nCycle = nc + nh + nd;
            for i = 1:app.NumberofCyclesSpinner.Value
                for j = 1:nCycle
                    if j <= nc
                        app.Bin.FoMode{1, j + nCycle*(i-1)} = 'C';
                    elseif j <= nh + nc && j > nc
                        app.Bin.FoMode{1, j + nCycle*(i-1)} = 'H';                   
                    else
                        app.Bin.FoMode{1, j + nCycle*(i-1)} = 'D';
                    end
                end
            end
            fillCycleDataTree(app);
        end

        % Button pushed function: HeatTransferSetButton
        function HeatTransferSetButtonPushed(app, event)
            app.Bin.T0 = app.ParticleInletTemperature.Value;
            app.Bin.Tinf = app.AmbientAirTemperature.Value;
            app.Bin.thetaA = 0.75; %(app.InternalAirTemperature.Value - app.Bin.Tinf)/(app.Bin.T0 - app.Bin.Tinf);
            app.Bin.hInf = 50; %app.AmbientHeatTransferCoefficient.Value;
            app.Bin.hp5C = 0.1; %app.InternalHeatTransferCoefficient.Value;
            app.Bin.hp5H = 0.2; %app.InternalHeatTransferCoefficient.Value;
            app.Bin.hp5D = 0.2; %app.InternalHeatTransferCoefficient.Value;
            fillHeatTransferPropertiesDataTree(app);
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            resetPrimal(app.Bin);
            setEmbeddedParams(app);
            tuneSimilarityParams(app); 
            app.CurrentStatusTextArea.Value = {'Begining Simulation'};
            pause (0.1);
            % simulate
%             simulateStorageThetaApp(app);
            % initialize temperature matrices 
            ns = length(app.Bin.zhat); nl = length(app.Bin.zbar);
            ms = length(app.Bin.rhat); ml = length(app.Bin.rbar);
            mt = length(app.Bin.rtop); nc = length(app.Bin.zcenter);
            if isempty(app.Bin.thetaS), app.Bin.thetaS = ones(nl, ml); end
            if isempty(app.Bin.thetaT), app.Bin.thetaT = ones(ns, mt); end
            if isempty(app.Bin.thetaC)
                app.Bin.thetaC = ones(nc, ms);
                computeThetaChat(app.Bin);
            end
            app.Bin.FS0 = app.Bin.thetaS;
            nch = length(app.Bin.zbarH); mch = length(app.Bin.rbarH);
            IC = ones(nch, mch); zIC = app.Bin.zbarH; rIC = app.Bin.rbarH;
            app.Bin.Tp = app.Bin.theta2TK(mean(IC(end, :)));
            % initialize insulation systems
            initializeBaseSys(app.Bin);
            initializeWallSys(app.Bin);
            initializeER(app.Bin);
            % iterate through each time step
            kStart = 2;                   
            for kf = kStart:length(app.Bin.Fo)
                currentStatus = printStatus(app.Bin, IC);
                app.CurrentStatusTextArea.Value = currentStatus;
                pause(0.1);
                app.Bin.FoNow = app.Bin.Fo(kf);
                app.Bin.FoModeNow = app.Bin.FoMode{1, kf};
                % normalize time step
                kn_ = mod(kf-1, app.Bin.ls) + 1;
                switch app.Bin.FoModeNow
                    case 'H'
                        % set initial condition
                        if kf == 1, prev = 1; else, prev = kf - 1; end
                        if app.Bin.FoMode{1, prev} ~= 'H'
                            matchHoldIC(app.Bin, IC, zIC, rIC, 0);
                        end     
                        app.Bin.Bi5 = app.Bin.Bi5H;
                        % compute stagnant holding solution for entire
                        % holding sequence
                        computeThetaH(app.Bin, app.Bin.FoNow);
                        % store temperature values in solution matrix
                        patchThetaH(app.Bin, kf, 0);
                        % update wall, base, and roof boundaries
                        ub = mean(mean(app.Bin.theta{end, 1}(1, :)))*ones(2, 1);
                        uw = mean(mean(app.Bin.theta{end, 1}(:, end)))*ones(2, 1);
                        app.Bin.Tp = app.Bin.theta2TK(mean(IC(end, :)));
                        Fo_ = [0, app.Bin.df];                                              
                        computeBaseSys(app.Bin, ub, Fo_);
                        computeWallSys(app.Bin, uw, Fo_);
                        computeER(app.Bin, Fo_);
                    case 'C'
                        % set initial condition
                        if kf == 1, prev = 1; else, prev = kf - 1; end
                        if app.Bin.FoMode{1, prev} ~= 'C'
                            matchChargeIC(app.Bin, IC, zIC, rIC, 0);
                        end            
                        app.Bin.Bi5 = app.Bin.Bi5C;
                        % compute charge temperature values for current
                        % time step
                        computeThetaH(app.Bin, app.Bin.FoNow);
                        % store temperature values in solution matrix
                        patchThetaC(app.Bin, kf, 0);
                        % update domain according to mass accounting
                        if kf ~= length(app.Bin.Fo)
                            if app.Bin.FoMode{1, kf+1} == 'C'                                              
                                updateDomainC(app.Bin);
                            end  
                        end
                        % update wall, base, and roof boundaries
                        ub = mean(mean(app.Bin.theta{end, 1}(1, :)))*ones(2, 1);
                        uw = mean(mean(app.Bin.theta{end, 1}(:, end)))*ones(2, 1);
                        app.Bin.Tp = app.Bin.theta2TK(mean(IC(end, :)));
                        Fo_ = [0, app.Bin.df];                      
                        computeBaseSys(app.Bin, ub, Fo_);
                        computeWallSys(app.Bin, uw, Fo_);
                        computeER(app.Bin, Fo_);
                    case 'D'
                        % set initial condition
                        if kf == 1, prev = 1; else, prev = kf - 1; end
                        if app.Bin.FoMode{1, prev} ~= 'D'
                            matchDischargeIC(app.Bin, IC, zIC, rIC, 0);
                        end 
                        app.Bin.Bi5 = app.Bin.Bi5D;                        
                        % compute temperature in top boundary
                        iterateThetaT(app.Bin);
                        recordTopLoss(app.Bin);
                        % compute temperature in center boundary
                        iterateThetaC(app.Bin);
                        % compute the stagnant boundary offset temperature
                        computeThetaI(app.Bin);
                        % compute temperature in stagnant region
                        iterateThetaS(app.Bin);
                        % store temperature values in solution matrix
                        patchTheta(app.Bin, kf, 0);
                        % update domain according to mass accounting
                        if kf ~= length(app.Bin.Fo)
                            if app.Bin.FoMode{1, kf+1} == 'D'
                                updateDomain(app.Bin);
                            end
                        end
                        % update wall, base, and roof boundaries
                        ub = mean(mean(app.Bin.theta{end, 1}(1, :)))*ones(2, 1);
                        uw = mean(mean(app.Bin.theta{end, 1}(:, end)))*ones(2, 1);
                        app.Bin.Tp = app.Bin.theta2TK(mean(IC(end, :)));
                        Fo_ = [0, app.Bin.df];                      
                        computeBaseSys(app.Bin, ub, Fo_);                       
                        computeWallSys(app.Bin, uw, Fo_);
                        computeER(app.Bin, Fo_);
                end
                IC = full(app.Bin.theta{kn_, 1});
                zIC = app.Bin.theta{kn_, 2}; rIC = app.Bin.theta{kn_, 3};                
                % hold if close to empty or full
                if app.Bin.ztop < 0.1 && kf ~= length(app.Bin.Fo)
                    if app.Bin.FoMode{1, kf+1} == 'D'
                        app.Bin.FoMode{1, kf+1} = 'H';
                    end                    
                end
                if app.Bin.ztop > 0.9 && kf ~= length(app.Bin.Fo)
                    if app.Bin.FoMode{1, kf+1} == 'C'
                        app.Bin.FoMode{1, kf+1} = 'H';
                    end                    
                end                                
            end 
            app.CurrentStatusTextArea.Value = {'Simulation Complete'};
        end

        % Button pushed function: LoadFromFileButton
        function LoadFromFileButtonPushed(app, event)
            [file, ~] = uigetfile('*.mat');
            setBinFromFile(app, file);
        end

        % Button pushed function: SaveToFileButton
        function SaveToFileButtonPushed(app, event)
            path = uigetdir;
            var = app.Bin;
            save(strcat(path, '/', 'savedBin.mat'), 'var');
        end

        % Button pushed function: SelectDataFolderPathButton
        function SelectDataFolderPathButtonPushed(app, event)
            path = uigetdir;
            mkdir(strcat(path, '/', 'thetaTest'));
            app.Bin.thetaFolder = strcat(path, '/', 'thetaTest');
        end

        % Button pushed function: Panel1_TemperatureAnimationButton
        function Panel1_TemperatureAnimationButtonPushed(app, event)
            % temperature contour animation w/o saved data
            filename = strcat(app.Bin.thetaFolder, '\', app.Panel1_filenameEditField.Value, '.gif');
            if app.Panel1_NonDimensionalCheckBox.Value == 1
                dimensions = 0;
            else
                dimensions = 1;
            end
            animateThetaNoWall(app.Bin, 0.5, filename, 1e10, dimensions, 1);
        end

        % Button pushed function: 
        % Panel2_ParticleOutletTemperatureButton
        function Panel2_ParticleOutletTemperatureButtonPushed(app, event)
            % outlet temperature plot and data export
            bulk = app.Panel2_BulkCalculationOverOrificeCheckBox.Value;
            filename = strcat(app.Bin.thetaFolder, '\', app.Panel2_filenameEditField.Value);
            if app.Panel1_NonDimensionalCheckBox.Value == 1
                dimensions = 0;
            else
                dimensions = 1;
            end
            if bulk == 1
                app.outletTempFig = plotOutletTempBulk(app.Bin, dimensions);
                saveOutletTempData(app, 'bulk', filename);
            else
                app.outletTempFig = plotOutletTempCL(app.Bin, dimensions);
                saveOutletTempData(app, 'cl', filename);
            end
            saveas(app.outletTempFig, filename, 'fig');
        end

        % Button pushed function: 
        % Panel3_ParticleDomainHeatLossButton
        function Panel3_ParticleDomainHeatLossButtonPushed(app, event)
            % particle domain heat loss plots and data export
            [app.particleHeatLossFig, qw, qb, qt, qTot, energyIn, energyLoss, percentLoss] = plotBinLosses(app.Bin, app.Panel3_WallCheckBox.Value, ...
                app.Panel3_BaseCheckBox.Value, app.Panel3_TopSurfaceCheckBox.Value, app.Panel3_TotalAllSurfacesCheckBox.Value);
            filename = strcat(app.Bin.thetaFolder, '\', app.Panel3_filenameEditField.Value);
            saveHeatLossData(app, filename, qw, qb, qt, qTot, energyIn, energyLoss, percentLoss);
            saveas(app.particleHeatLossFig, filename, 'fig');            
        end

        % Button pushed function: Panel4_InsulationLayersButton
        function Panel4_InsulationLayersButtonPushed(app, event)
            % wall and base plots and data export                                 
            filename = strcat(app.Bin.thetaFolder, '\', app.Panel4_filenameEditField.Value);
            if app.Panel4_WallCheckBox.Value
                [app.wallTempFig, ~, wallTemps] = plotWallTemp(app.Bin, 1);
                [app.wallHeatFluxFig, ~, wallFlux] = plotWallFlux(app.Bin, 1);
            end
            if app.Panel4_BaseCheckBox.Value
                [app.baseTempFig, ~, baseTemps] = plotBaseTemp(app.Bin, 1); 
                [app.baseHeatFluxFig, ~, baseFlux] = plotBaseFlux(app.Bin, 1);
            end
            saveInsulationData(app, filename, wallTemps, baseTemps, wallFlux, baseFlux);
        end

        % Button pushed function: 
        % Panel7_BulkVolumetricTemperatureButton
        function Panel7_BulkVolumetricTemperatureButtonPushed(app, event)
            % bulk volumetric temperature in particle domain plot and daa
            % export
            filename = strcat(app.Bin.thetaFolder, '\', app.Panel7_filenameEditField.Value);
            if app.Panel7_NonDimensionalCheckBox.Value == 1
                dimensions = 0;
            else
                dimensions = 1;
            end
            [app.bulkTempFig, bulkTemp] = plotBulkVolumetricTemp(app.Bin, dimensions);
            saveBulkTempData(app, filename, bulkTemp);
        end

        % Button pushed function: Panel5_RadialTemperatureLineButton
        function Panel5_RadialTemperatureLineButtonPushed(app, event)
            % exports temporal and spatial data for the specified radial
            % line and creates a gif to show the results
            filename = strcat(app.Bin.thetaFolder, '\', app.Panel5_filenameEditField.Value, '.gif');
            if app.Panel5_NonDimensionalCheckBox.Value == 1
                dimensions = 0;
            else
                dimensions = 1;
            end
            if app.Panel5_VerticalLocationEditField.Value > 0.9*app.Bin.Hp
                zCoord = 0.9;
            elseif app.Panel5_VerticalLocationEditField.Value < 0
                zCoord = 0;
            else
                zCoord = app.Panel5_VerticalLocationEditField.Value/app.Bin.Hp;
            end
            radialData = animateRadialTempLine(app.Bin, 0.5, filename, zCoord, dimensions);
        end

        % Button pushed function: 
        % Panel6_VerticalTemperatureLineButton
        function Panel6_VerticalTemperatureLineButtonPushed(app, event)
            % exports temporal and spatial data for the specified vertical
            % line and creates a gif to show the results
            filename = strcat(app.Bin.thetaFolder, '\', app.Panel6_filenameEditField.Value, '.gif');
            if app.Panel6_NonDimensionalCheckBox.Value == 1
                dimensions = 0;
            else
                dimensions = 1;
            end
            if app.Panel6_RadialLocationEditField.Value > app.Bin.bp*app.Bin.Hp
                rCoord = app.Bin.bp;
            elseif app.Panel6_RadialLocationEditField.Value < 0
                rCoord = 0;
            else
                rCoord = app.Panel6_RadialLocationEditField.Value/app.Bin.Hp;
            end
            verticalData = animateVerticalTempLine(app.Bin, 0.5, filename, rCoord, dimensions);            
        end

        % Button pushed function: LayerApplyButton_2
        function LayerApplyButton_2Pushed(app, event)
            % Create Node and subnodes with descriptive info
            wallSize = size(app.InsWallLayers, 2);                
            layerNum = app.LayerNumberSpinner_2.Value;
            name = app.MaterialNameEditField_2.Value;
            layerThicknesses = str2double(strsplit(app.ThicknessEditField_2.Value));
            k_ = str2double(strsplit(app.ThermalConductivityEditField_2.Value));
            rho_ = str2double(strsplit(app.DensityEditField_2.Value));
            cp_ = str2double(strsplit(app.SpecificHeatCapacityEditField_2.Value));
            elements_ = str2double(strsplit(app.LumpedElementsField_2.Value));
            hc_ = str2double(strsplit(app.ContactResistanceField_2.Value)); 
            if layerNum <= wallSize
                % force to end
                layerNum = wallSize + 1;
            end
            % append layer to the end  
            app.InsWallLayers{layerNum} = uitreenode(app.WallTestTree.Children(1), 'Text', sprintf('%d: %s', layerNum, app.MaterialNameEditField_2.Value));
            uitreenode(app.InsWallLayers{layerNum}, 'Text', sprintf('t = [%s] m', app.ThicknessEditField_2.Value));
            uitreenode(app.InsWallLayers{layerNum}, 'Text', sprintf('k = [%s] W/mK', app.ThermalConductivityEditField_2.Value));
            uitreenode(app.InsWallLayers{layerNum}, 'Text', sprintf('rho = [%s] kg/m3', app.DensityEditField_2.Value));
            uitreenode(app.InsWallLayers{layerNum}, 'Text', sprintf('c = [%s] J/kgK\n', app.SpecificHeatCapacityEditField_2.Value));
            uitreenode(app.InsWallLayers{layerNum}, 'Text', sprintf('# elements = [%s]', app.LumpedElementsField_2.Value)); 
            uitreenode(app.InsWallLayers{layerNum}, 'Text', sprintf('hc = [%s] W/m2K\n', app.ContactResistanceField_2.Value));
            
            % generate parametric test cases


            app.appWallInsulation{layerNum, 1} = app.MaterialNameEditField_2.Value;                 
            if layerNum == 1
                app.appWallInsulation{layerNum, 2} = [app.Bin.bp*app.Bin.Hp, app.Bin.bp*app.Bin.Hp + layerThicknesses];
            else
                app.appWallInsulation{layerNum, 2} = [app.Bin.appWallInsulation{layerNum - 1, 2}(2), ...
                    app.appWallInsulation{layerNum - 1, 2}(2) + layerThicknesses];
            end
            app.appWallInsulation{layerNum, 3} = str2double(strsplit(app.ThermalConductivityEditField_2.Value));
            app.appWallInsulation{layerNum, 4} = str2double(strsplit(app.DensityEditField_2.Value));
            app.appWallInsulation{layerNum, 5} = str2double(strsplit(app.SpecificHeatCapacityEditField_2.Value));
            app.appWallInsulation{layerNum, 6} = str2double(strsplit(app.LumpedElementsField_2.Value));
            app.appWallInsulation{layerNum, 7} = str2double(strsplit(app.ContactResistanceField_2.Value));        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BinSetupTab = uitab(app.TabGroup);
            app.BinSetupTab.Title = 'Bin Setup';
            app.LayerPropertiesPanel = uipanel(app.BinSetupTab);
            app.LayerPropertiesPanel.Title = 'Layer Properties';
            app.MaterialNameEditFieldLabel = uilabel(app.LayerPropertiesPanel);
            app.MaterialNameEditFieldLabel.Text = 'Material Name';
            app.MaterialNameEditField = uieditfield(app.LayerPropertiesPanel, 'text');
            app.MaterialNameEditField.Value = 'layer 1';
            app.ThicknessmEditFieldLabel = uilabel(app.LayerPropertiesPanel);
            app.ThicknessmEditFieldLabel.Text = 'Thickness (m)';
            app.ThicknessEditField = uieditfield(app.LayerPropertiesPanel, 'numeric');
            app.ThicknessEditField.Limits = [0 Inf];
            app.LayerNumberSpinnerLabel = uilabel(app.LayerPropertiesPanel);
            app.LayerNumberSpinnerLabel.Text = 'Layer Number';
            app.LayerNumberSpinner = uispinner(app.LayerPropertiesPanel);
            app.LayerNumberSpinner.Limits = [0 Inf];
            app.LayerNumberSpinner.Value = 1;
            app.ThermalConductivityWmKEditFieldLabel = uilabel(app.LayerPropertiesPanel);
            app.ThermalConductivityWmKEditFieldLabel.Text = 'Thermal Conductivity (W/mK)';
            app.ThermalConductivityEditField = uieditfield(app.LayerPropertiesPanel, 'numeric');
            app.ThermalConductivityEditField.Limits = [0 Inf];
            app.ThermalConductivityEditField.Value = 1;
            app.Densitykgm3EditFieldLabel = uilabel(app.LayerPropertiesPanel);
            app.Densitykgm3EditFieldLabel.Text = 'Density (kg/m3)';
            app.DensityEditField = uieditfield(app.LayerPropertiesPanel, 'numeric');
            app.DensityEditField.Limits = [0 Inf];
            app.DensityEditField.Value = 1000;
            app.SpecificHeatCapacityJkgKEditFieldLabel = uilabel(app.LayerPropertiesPanel);
            app.SpecificHeatCapacityJkgKEditFieldLabel.Text = 'Specific Heat Capacity (J/kgK)';
            app.SpecificHeatCapacityEditField = uieditfield(app.LayerPropertiesPanel, 'numeric');
            app.SpecificHeatCapacityEditField.Limits = [0 Inf];
            app.SpecificHeatCapacityEditField.Value = 1000;
            app.LayerApplyButton = uibutton(app.LayerPropertiesPanel, 'push');
            app.LayerApplyButton.ButtonPushedFcn = createCallbackFcn(app, @LayerApplyButtonPushed, true);
            app.LayerApplyButton.Text = 'Apply';
            app.LayerRemoveButton = uibutton(app.LayerPropertiesPanel, 'push');
            app.LayerRemoveButton.ButtonPushedFcn = createCallbackFcn(app, @LayerRemoveButtonPushed, true);
            app.LayerRemoveButton.Text = 'Remove';
            app.WallCheckBox = uicheckbox(app.LayerPropertiesPanel);
            app.WallCheckBox.Text = 'Wall';
            app.BaseCheckBox = uicheckbox(app.LayerPropertiesPanel);
            app.BaseCheckBox.Text = 'Base';
            app.RoofCheckBox = uicheckbox(app.LayerPropertiesPanel);
            app.RoofCheckBox.Text = 'Roof';
            app.ResetButton = uibutton(app.LayerPropertiesPanel, 'push');
            app.ResetButton.Text = 'Reset';
            app.Costm3Label = uilabel(app.LayerPropertiesPanel);
            app.Costm3Label.Text = 'Cost ($/m3)';
            app.LayerCostEditField = uieditfield(app.LayerPropertiesPanel, 'numeric');
            app.LayerCostEditField.Limits = [0 Inf];
            app.LayerCostEditField.Value = 100;
            app.ColorDropDownLabel = uilabel(app.LayerPropertiesPanel);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.LayerPropertiesPanel);
            app.ColorDropDown.Items = {'black', 'red', 'blue', 'green', 'magenta', 'cyan', 'yellow'};
            app.ColorDropDown.ItemsData = {'-k', '-r', '-b', '-g', '-m', '-c', '-y', '', ''};
            app.ColorDropDown.Value = '-k';
            app.NumberofLumpedElementsLabel = uilabel(app.LayerPropertiesPanel);
            app.NumberofLumpedElementsLabel.Text = 'Number of Lumped Elements';
            app.LumpedElementsField = uieditfield(app.LayerPropertiesPanel, 'numeric');
            app.LumpedElementsField.Limits = [0 1000];
            app.LumpedElementsField.RoundFractionalValues = 'on';
            app.LumpedElementsField.ValueDisplayFormat = '%11.0g';
            app.LumpedElementsField.Value = 10;
            app.ContactConductanceWm2KLabel = uilabel(app.LayerPropertiesPanel);
            app.ContactConductanceWm2KLabel.Text = 'Contact Conductance (W/m2K)';
            app.ContactResistanceField = uieditfield(app.LayerPropertiesPanel, 'numeric');
            app.ContactResistanceField.Limits = [1e-06 1000000];
            app.ContactResistanceField.Value = 5;
            app.BinDiagramPanel = uipanel(app.BinSetupTab);
            app.BinDiagramPanel.Title = 'Bin Diagram';
            app.BinDiagram = uiaxes(app.BinDiagramPanel);
            title(app.BinDiagram, '')
            xlabel(app.BinDiagram, 'r/H')
            ylabel(app.BinDiagram, 'z/H')
            app.BinGeometryPanel = uipanel(app.BinSetupTab);
            app.BinGeometryPanel.Title = 'Bin Geometry';
            app.GeometrySetButton = uibutton(app.BinGeometryPanel, 'push');
            app.GeometrySetButton.ButtonPushedFcn = createCallbackFcn(app, @GeometrySetButtonPushed, true);
            app.GeometrySetButton.Text = 'Set';
            app.TESBinHeightHmLabel = uilabel(app.BinGeometryPanel);
            app.TESBinHeightHmLabel.Text = 'TES Bin Height, H (m)';
            app.BinHeightEditField = uieditfield(app.BinGeometryPanel, 'numeric');
            app.BinHeightEditField.Limits = [0 Inf];
            app.BinHeightEditField.Value = 7;
            app.TESBinDiameterDmLabel = uilabel(app.BinGeometryPanel);
            app.TESBinDiameterDmLabel.Text = 'TES Bin Diameter, D (m)';
            app.BinDiameterEditField = uieditfield(app.BinGeometryPanel, 'numeric');
            app.BinDiameterEditField.Limits = [0 Inf];
            app.BinDiameterEditField.Value = 4.5;
            app.BinDimensionsImage = uiimage(app.BinSetupTab);
            app.BinDimensionsImage.ImageSource = 'BinDimensions.png';
            app.LayersTree = uitree(app.BinSetupTab);
            app.WallNode = uitreenode(app.LayersTree);
            app.WallNode.Text = 'Wall';
            app.BaseNode = uitreenode(app.LayersTree);
            app.BaseNode.Text = 'Base';
            app.RoofNode = uitreenode(app.LayersTree);
            app.RoofNode.Text = 'Roof';
            app.LoadFromFileButton = uibutton(app.BinSetupTab, 'push');
            app.LoadFromFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFromFileButtonPushed, true);
            app.LoadFromFileButton.Text = 'Load From File';
            app.SaveToFileButton = uibutton(app.BinSetupTab, 'push');
            app.SaveToFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveToFileButtonPushed, true);
            app.SaveToFileButton.Text = 'Save To File';
            app.SimulationSetupTab = uitab(app.TabGroup);
            app.SimulationSetupTab.Title = 'Simulation Setup';
            app.SimulationOptionsTabGroup = uitabgroup(app.SimulationSetupTab);
            app.MainTab = uitab(app.SimulationOptionsTabGroup);
            app.MainTab.Title = 'Main';
            app.ParticleParametersPanel = uipanel(app.MainTab);
            app.ParticleParametersPanel.Title = 'Particle Parameters';
            app.ParticleThermalConductivityWmKLabel_2 = uilabel(app.ParticleParametersPanel);
            app.ParticleThermalConductivityWmKLabel_2.Text = 'Particle Thermal Conductivity (W/mK)';
            app.ParticleThermalConductivity = uieditfield(app.ParticleParametersPanel, 'numeric');
            app.ParticleThermalConductivity.Limits = [1e-05 Inf];
            app.ParticleThermalConductivity.Value = 0.4;
            app.PackedParticleBulkDensitykgm3Label_2 = uilabel(app.ParticleParametersPanel);
            app.PackedParticleBulkDensitykgm3Label_2.Text = 'Packed Particle Bulk Density (kg/m3)';
            app.ParticleDensity = uieditfield(app.ParticleParametersPanel, 'numeric');
            app.ParticleDensity.Limits = [1e-05 Inf];
            app.ParticleDensity.Value = 2000;
            app.ParticleSpecificHeatCapacityJkgKLabel_2 = uilabel(app.ParticleParametersPanel);
            app.ParticleSpecificHeatCapacityJkgKLabel_2.Text = 'Particle Specific Heat Capacity (J/kgK)';
            app.ParticleHeatCapacity = uieditfield(app.ParticleParametersPanel, 'numeric');
            app.ParticleHeatCapacity.Limits = [1e-05 Inf];
            app.ParticleHeatCapacity.Value = 1026;
            app.ParticleBulkDynamicViscositym2sLabel_2 = uilabel(app.ParticleParametersPanel);
            app.ParticleBulkDynamicViscositym2sLabel_2.Text = 'Particle Bulk Dynamic Viscosity (m2/s)';
            app.ParticleDynamicViscosity = uieditfield(app.ParticleParametersPanel, 'numeric');
            app.ParticleDynamicViscosity.Limits = [1e-10 Inf];
            app.ParticleDynamicViscosity.Value = 2.5e-08;
            app.ParticleSetButton = uibutton(app.ParticleParametersPanel, 'push');
            app.ParticleSetButton.ButtonPushedFcn = createCallbackFcn(app, @ParticleSetButtonPushed, true);
            app.ParticleSetButton.Text = 'Set';
            app.ParticleDataTree = uitree(app.ParticleParametersPanel);
            app.StorageCyclePanel = uipanel(app.MainTab);
            app.StorageCyclePanel.Title = 'Storage Cycle';
            app.ChargeDurrationhEditFieldLabel = uilabel(app.StorageCyclePanel);
            app.ChargeDurrationhEditFieldLabel.Text = 'Charge Durration (h)';
            app.ChargeDurrationEditField = uieditfield(app.StorageCyclePanel, 'numeric');
            app.ChargeDurrationEditField.Limits = [0 Inf];
            app.ChargeDurrationEditField.Value = 6;
            app.NumberofCyclesSpinnerLabel = uilabel(app.StorageCyclePanel);
            app.NumberofCyclesSpinnerLabel.Text = 'Number of Cycles ';
            app.NumberofCyclesSpinner = uispinner(app.StorageCyclePanel);
            app.NumberofCyclesSpinner.Limits = [0 Inf];
            app.NumberofCyclesSpinner.Value = 1;
            app.HoldDurrationhEditFieldLabel = uilabel(app.StorageCyclePanel);
            app.HoldDurrationhEditFieldLabel.Text = 'Hold Durration (h)';
            app.HoldDurrationEditField = uieditfield(app.StorageCyclePanel, 'numeric');
            app.HoldDurrationEditField.Limits = [0 Inf];
            app.HoldDurrationEditField.Value = 10;
            app.DischargeDurrationhEditFieldLabel = uilabel(app.StorageCyclePanel);
            app.DischargeDurrationhEditFieldLabel.Text = 'Discharge Durration (h)';
            app.DischargeDurrationEditField = uieditfield(app.StorageCyclePanel, 'numeric');
            app.DischargeDurrationEditField.Limits = [0 Inf];
            app.DischargeDurrationEditField.Value = 8;
            app.CycleSetButton = uibutton(app.StorageCyclePanel, 'push');
            app.CycleSetButton.ButtonPushedFcn = createCallbackFcn(app, @CycleSetButtonPushed, true);
            app.CycleSetButton.Text = 'Set';
            app.TimestepsEditFieldLabel = uilabel(app.StorageCyclePanel);
            app.TimestepsEditFieldLabel.Text = 'Timestep (s)';
            app.TimestepEditField = uieditfield(app.StorageCyclePanel, 'numeric');
            app.TimestepEditField.Limits = [1e-06 Inf];
            app.TimestepEditField.Value = 60;
            app.StorageCycleDataTree = uitree(app.StorageCyclePanel);
            app.InletMassFlowRatekgsEditFieldLabel = uilabel(app.StorageCyclePanel);
            app.InletMassFlowRatekgsEditFieldLabel.Text = 'Inlet Mass Flow Rate (kg/s)';
            app.InletMassFlowRateEditField = uieditfield(app.StorageCyclePanel, 'numeric');
            app.InletMassFlowRateEditField.Limits = [0 Inf];
            app.InletMassFlowRateEditField.Value = 9;
            app.OutletMassFlowRatekgsEditFieldLabel = uilabel(app.StorageCyclePanel);
            app.OutletMassFlowRatekgsEditFieldLabel.Text = 'Outlet Mass Flow Rate (kg/s)';
            app.OutletMassFlowRateEditField = uieditfield(app.StorageCyclePanel, 'numeric');
            app.OutletMassFlowRateEditField.Limits = [0 Inf];
            app.OutletMassFlowRateEditField.Value = 5;
            app.HeatTransferParametersPanel = uipanel(app.MainTab);
            app.HeatTransferParametersPanel.Title = 'Heat Transfer Parameters';
            app.ParticleInletTemperatureCLabel = uilabel(app.HeatTransferParametersPanel);
            app.ParticleInletTemperatureCLabel.Text = 'Particle Inlet Temperature (°C)';
            app.ParticleInletTemperature = uieditfield(app.HeatTransferParametersPanel, 'numeric');
            app.ParticleInletTemperature.Value = 800;
            app.InternalAirTemperatureCLabel = uilabel(app.HeatTransferParametersPanel);
            app.InternalAirTemperatureCLabel.Text = 'Internal Air Temperature (°C)';
            app.InternalAirTemperature = uieditfield(app.HeatTransferParametersPanel, 'numeric');
            app.InternalAirTemperature.Value = 400;
            app.AmbientHeatTransferCoefficientWm2KLabel_2 = uilabel(app.HeatTransferParametersPanel);
            app.AmbientHeatTransferCoefficientWm2KLabel_2.Text = 'Ambient Heat Transfer Coefficient (W/m2K)';
            app.AmbientHeatTransferCoefficient = uieditfield(app.HeatTransferParametersPanel, 'numeric');
            app.AmbientHeatTransferCoefficient.Limits = [1e-05 Inf];
            app.AmbientHeatTransferCoefficient.Value = 5;
            app.InternalHeatTransferCoefficientWm2KLabel = uilabel(app.HeatTransferParametersPanel);
            app.InternalHeatTransferCoefficientWm2KLabel.Text = 'Internal Heat Transfer Coefficient (W/m2K)';
            app.InternalHeatTransferCoefficient = uieditfield(app.HeatTransferParametersPanel, 'numeric');
            app.InternalHeatTransferCoefficient.Limits = [1e-05 Inf];
            app.InternalHeatTransferCoefficient.Value = 5;
            app.HeatTransferSetButton = uibutton(app.HeatTransferParametersPanel, 'push');
            app.HeatTransferSetButton.ButtonPushedFcn = createCallbackFcn(app, @HeatTransferSetButtonPushed, true);
            app.HeatTransferSetButton.Text = 'Set';
            app.IncludeInternalRadiationExchangeCheckBox = uicheckbox(app.HeatTransferParametersPanel);
            app.IncludeInternalRadiationExchangeCheckBox.Text = 'Include Internal Radiation Exchange';
            app.IncludeInternalRadiationExchangeCheckBox.Value = true;
            app.HeatTransferDataTree = uitree(app.HeatTransferParametersPanel);
            app.AmbientAirTemperatureCLabel = uilabel(app.HeatTransferParametersPanel);
            app.AmbientAirTemperatureCLabel.Text = 'Ambient Air Temperature (°C)';
            app.AmbientAirTemperature = uieditfield(app.HeatTransferParametersPanel, 'numeric');
            app.AmbientAirTemperature.Value = 20;
            app.StartSimulationButton = uibutton(app.MainTab, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.SelectDataFolderPathButton = uibutton(app.MainTab, 'push');
            app.SelectDataFolderPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataFolderPathButtonPushed, true);
            app.SelectDataFolderPathButton.Text = 'Select Data Folder Path';
            app.CurrentStatusTextArea = uitextarea(app.MainTab);
            app.MeshSelectionTab = uitab(app.SimulationOptionsTabGroup);
            app.MeshSelectionTab.Title = 'Mesh Selection';
            app.ComingsoonLabel = uilabel(app.MeshSelectionTab);
            app.ComingsoonLabel.Text = 'Coming soon...';
            app.ResultsViewerTab = uitab(app.TabGroup);
            app.ResultsViewerTab.Title = 'Results Viewer';
            app.Panel_1 = uipanel(app.ResultsViewerTab);
            app.Panel1_TemperatureAnimationButton = uibutton(app.Panel_1, 'push');
            app.Panel1_TemperatureAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @Panel1_TemperatureAnimationButtonPushed, true);
            app.Panel1_TemperatureAnimationButton.Text = 'Temperature Animation';
            app.Panel1_NonDimensionalCheckBox = uicheckbox(app.Panel_1);
            app.Panel1_NonDimensionalCheckBox.Text = 'Non-Dimensional';
            app.filenameEditFieldLabel = uilabel(app.Panel_1);
            app.filenameEditFieldLabel.Text = 'filename';
            app.Panel1_filenameEditField = uieditfield(app.Panel_1, 'text');
            app.Panel1_filenameEditField.Value = 'TempAnimation';
            app.Panel_2 = uipanel(app.ResultsViewerTab);
            app.Panel2_ParticleOutletTemperatureButton = uibutton(app.Panel_2, 'push');
            app.Panel2_ParticleOutletTemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @Panel2_ParticleOutletTemperatureButtonPushed, true);
            app.Panel2_ParticleOutletTemperatureButton.Text = 'Particle Outlet Temperature';
            app.Panel2_BulkCalculationOverOrificeCheckBox = uicheckbox(app.Panel_2);
            app.Panel2_BulkCalculationOverOrificeCheckBox.Text = 'Bulk Calculation Over Orifice';
            app.Panel2_BulkCalculationOverOrificeCheckBox.Value = true;
            app.Panel2_NonDimensionalCheckBox = uicheckbox(app.Panel_2);
            app.Panel2_NonDimensionalCheckBox.Text = 'Non-Dimensional';
            app.filenameEditField_3Label = uilabel(app.Panel_2);
            app.filenameEditField_3Label.Text = 'filename';
            app.Panel2_filenameEditField = uieditfield(app.Panel_2, 'text');
            app.Panel2_filenameEditField.Value = 'OutletTemp';
            app.Panel_3 = uipanel(app.ResultsViewerTab);
            app.Panel3_ParticleDomainHeatLossButton = uibutton(app.Panel_3, 'push');
            app.Panel3_ParticleDomainHeatLossButton.ButtonPushedFcn = createCallbackFcn(app, @Panel3_ParticleDomainHeatLossButtonPushed, true);
            app.Panel3_ParticleDomainHeatLossButton.Text = 'Particle Domain Heat Loss';
            app.Panel3_TotalAllSurfacesCheckBox = uicheckbox(app.Panel_3);
            app.Panel3_TotalAllSurfacesCheckBox.Text = 'Total (All Surfaces)';
            app.Panel3_TotalAllSurfacesCheckBox.Value = true;
            app.Panel3_TopSurfaceCheckBox = uicheckbox(app.Panel_3);
            app.Panel3_TopSurfaceCheckBox.Text = 'Top Surface';
            app.Panel3_TopSurfaceCheckBox.Value = true;
            app.Panel3_WallCheckBox = uicheckbox(app.Panel_3);
            app.Panel3_WallCheckBox.Text = 'Wall';
            app.Panel3_WallCheckBox.Value = true;
            app.Panel3_BaseCheckBox = uicheckbox(app.Panel_3);
            app.Panel3_BaseCheckBox.Text = 'Base';
            app.Panel3_BaseCheckBox.Value = true;
            app.filenameEditField_4Label = uilabel(app.Panel_3);
            app.filenameEditField_4Label.Text = 'filename';
            app.Panel3_filenameEditField = uieditfield(app.Panel_3, 'text');
            app.Panel3_filenameEditField.Value = 'ParticleHeatLoss';
            app.Panel3_BaseCheckBox_2 = uicheckbox(app.Panel_3);
            app.Panel3_BaseCheckBox_2.Text = 'Roof';
            app.Panel3_BaseCheckBox_2.Value = true;
            app.Panel_4 = uipanel(app.ResultsViewerTab);
            app.Panel4_InsulationLayersButton = uibutton(app.Panel_4, 'push');
            app.Panel4_InsulationLayersButton.ButtonPushedFcn = createCallbackFcn(app, @Panel4_InsulationLayersButtonPushed, true);
            app.Panel4_InsulationLayersButton.Text = 'Insulation Layers';
            app.Panel4_WallCheckBox = uicheckbox(app.Panel_4);
            app.Panel4_WallCheckBox.Text = 'Wall';
            app.Panel4_WallCheckBox.Value = true;
            app.Panel4_BaseCheckBox = uicheckbox(app.Panel_4);
            app.Panel4_BaseCheckBox.Text = 'Base';
            app.Panel4_BaseCheckBox.Value = true;
            app.filenameEditField_5Label = uilabel(app.Panel_4);
            app.filenameEditField_5Label.Text = 'filename';
            app.Panel4_filenameEditField = uieditfield(app.Panel_4, 'text');
            app.Panel4_filenameEditField.Value = 'Insulation';
            app.Panel4_TempCheckBox = uicheckbox(app.Panel_4);
            app.Panel4_TempCheckBox.Text = 'Temperature';
            app.Panel4_TempCheckBox.Value = true;
            app.Panel4_HeatFluxCheckBox = uicheckbox(app.Panel_4);
            app.Panel4_HeatFluxCheckBox.Text = 'Heat Flux';
            app.Panel4_HeatFluxCheckBox.Value = true;
            app.Panel4_BaseCheckBox_2 = uicheckbox(app.Panel_4);
            app.Panel4_BaseCheckBox_2.Text = 'Roof';
            app.Panel4_BaseCheckBox_2.Value = true;
            app.Panel_5 = uipanel(app.ResultsViewerTab);
            app.Panel5_RadialTemperatureLineButton = uibutton(app.Panel_5, 'push');
            app.Panel5_RadialTemperatureLineButton.ButtonPushedFcn = createCallbackFcn(app, @Panel5_RadialTemperatureLineButtonPushed, true);
            app.Panel5_RadialTemperatureLineButton.Text = 'Radial Temperature Line';
            app.VerticalLocationmEditFieldLabel = uilabel(app.Panel_5);
            app.VerticalLocationmEditFieldLabel.Text = 'Vertical Location (m)';
            app.Panel5_VerticalLocationEditField = uieditfield(app.Panel_5, 'numeric');
            app.Panel5_VerticalLocationEditField.Limits = [0 Inf];
            app.filenameEditField_6Label = uilabel(app.Panel_5);
            app.filenameEditField_6Label.Text = 'filename';
            app.Panel5_filenameEditField = uieditfield(app.Panel_5, 'text');
            app.Panel5_filenameEditField.Value = 'RadialTemp';
            app.Panel5_NonDimensionalCheckBox = uicheckbox(app.Panel_5);
            app.Panel5_NonDimensionalCheckBox.Text = 'Non-Dimensional';
            app.Panel_6 = uipanel(app.ResultsViewerTab);
            app.Panel6_VerticalTemperatureLineButton = uibutton(app.Panel_6, 'push');
            app.Panel6_VerticalTemperatureLineButton.ButtonPushedFcn = createCallbackFcn(app, @Panel6_VerticalTemperatureLineButtonPushed, true);
            app.Panel6_VerticalTemperatureLineButton.Text = 'Vertical Temperature Line';
            app.RadialLocationmEditFieldLabel = uilabel(app.Panel_6);
            app.RadialLocationmEditFieldLabel.Text = 'Radial Location (m)';
            app.Panel6_RadialLocationEditField = uieditfield(app.Panel_6, 'numeric');
            app.Panel6_RadialLocationEditField.Limits = [0 Inf];
            app.filenameEditField_7Label = uilabel(app.Panel_6);
            app.filenameEditField_7Label.Text = 'filename';
            app.Panel6_filenameEditField = uieditfield(app.Panel_6, 'text');
            app.Panel6_filenameEditField.Value = 'VerticalTemp';
            app.Panel6_NonDimensionalCheckBox = uicheckbox(app.Panel_6);
            app.Panel6_NonDimensionalCheckBox.Text = 'Non-Dimensional';
            app.Panel_7 = uipanel(app.ResultsViewerTab);
            app.Panel7_BulkVolumetricTemperatureButton = uibutton(app.Panel_7, 'push');
            app.Panel7_BulkVolumetricTemperatureButton.ButtonPushedFcn = createCallbackFcn(app, @Panel7_BulkVolumetricTemperatureButtonPushed, true);
            app.Panel7_BulkVolumetricTemperatureButton.Text = 'Bulk Volumetric Temperature';
            app.Panel7_NonDimensionalCheckBox = uicheckbox(app.Panel_7);
            app.Panel7_NonDimensionalCheckBox.Text = 'Non-Dimensional';
            app.filenameEditField_2Label = uilabel(app.Panel_7);
            app.filenameEditField_2Label.Text = 'filename';
            app.Panel7_filenameEditField = uieditfield(app.Panel_7, 'text');
            app.Panel7_filenameEditField.Value = 'BulkVolumetricTemp';
            app.InsulationTestingTab = uitab(app.TabGroup);
            app.InsulationTestingTab.Title = 'Insulation Testing';
            app.InsulationTestingTabGroup = uitabgroup(app.InsulationTestingTab);
            app.WallTab = uitab(app.InsulationTestingTabGroup);
            app.WallTab.Title = 'Wall';
            app.WallLayerPropertiesPanel = uipanel(app.WallTab);
            app.WallLayerPropertiesPanel.Title = 'Layer Properties';
            app.MaterialNameEditField_2Label = uilabel(app.WallLayerPropertiesPanel);
            app.MaterialNameEditField_2Label.Text = 'Material Name';
            app.MaterialNameEditField_2 = uieditfield(app.WallLayerPropertiesPanel, 'text');
            app.MaterialNameEditField_2.Value = 'layer 1';
            app.LayerNumberSpinner_2Label = uilabel(app.WallLayerPropertiesPanel);
            app.LayerNumberSpinner_2Label.Text = 'Layer Number';
            app.LayerNumberSpinner_2 = uispinner(app.WallLayerPropertiesPanel);
            app.LayerNumberSpinner_2.Limits = [0 Inf];
            app.LayerNumberSpinner_2.Value = 1;
            app.LayerApplyButton_2 = uibutton(app.WallLayerPropertiesPanel, 'push');
            app.LayerApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @LayerApplyButton_2Pushed, true);
            app.LayerApplyButton_2.Text = 'Apply';
            app.LayerRemoveButton_2 = uibutton(app.WallLayerPropertiesPanel, 'push');
            app.LayerRemoveButton_2.Text = 'Remove';
            app.ResetButton_2 = uibutton(app.WallLayerPropertiesPanel, 'push');
            app.ResetButton_2.Text = 'Reset';
            app.ThicknessmEditFieldLabel_2 = uilabel(app.WallLayerPropertiesPanel);
            app.ThicknessmEditFieldLabel_2.Text = 'Thickness (m)';
            app.ThicknessEditField_2 = uieditfield(app.WallLayerPropertiesPanel, 'text');
            app.ThicknessEditField_2.Value = '0';
            app.NumberofLumpedElementsLabel_2 = uilabel(app.WallLayerPropertiesPanel);
            app.NumberofLumpedElementsLabel_2.Text = 'Number of Lumped Elements';
            app.LumpedElementsField_2 = uieditfield(app.WallLayerPropertiesPanel, 'text');
            app.LumpedElementsField_2.Value = '0';
            app.ContactConductaceWm2KLabel = uilabel(app.WallLayerPropertiesPanel);
            app.ContactConductaceWm2KLabel.Text = 'Contact Conductace (W/m2K)';
            app.ContactResistanceField_2 = uieditfield(app.WallLayerPropertiesPanel, 'text');
            app.ContactResistanceField_2.Value = '0';
            app.ThermalConductivityWmKLabel = uilabel(app.WallLayerPropertiesPanel);
            app.ThermalConductivityWmKLabel.Text = 'Thermal Conductivity (W/mK)';
            app.ThermalConductivityEditField_2 = uieditfield(app.WallLayerPropertiesPanel, 'text');
            app.ThermalConductivityEditField_2.Value = '0';
            app.Densitykgm3Label = uilabel(app.WallLayerPropertiesPanel);
            app.Densitykgm3Label.Text = 'Density (kg/m3';
            app.DensityEditField_2 = uieditfield(app.WallLayerPropertiesPanel, 'text');
            app.DensityEditField_2.Value = '0';
            app.SpecificHeatCapacityJkgKLabel = uilabel(app.WallLayerPropertiesPanel);
            app.SpecificHeatCapacityJkgKLabel.Text = 'Specific Heat Capacity (J/kgK)';
            app.SpecificHeatCapacityEditField_2 = uieditfield(app.WallLayerPropertiesPanel, 'text');
            app.SpecificHeatCapacityEditField_2.Value = '0';
            app.Costm3Label_3 = uilabel(app.WallLayerPropertiesPanel);
            app.Costm3Label_3.Text = 'Cost ($/m3)';
            app.LayerCostEditField_2 = uieditfield(app.WallLayerPropertiesPanel, 'text');
            app.LayerCostEditField_2.Value = '0';
            app.WallTestTree = uitree(app.WallTab);
            app.WallLayersNode = uitreenode(app.WallTestTree);
            app.WallLayersNode.Text = 'Wall Layers';
            app.SelectWallTestDataFolderPathButton = uibutton(app.WallTab, 'push');
            app.SelectWallTestDataFolderPathButton.Text = 'Select Data Folder Path';
            app.StartWallSimulationButton = uibutton(app.WallTab, 'push');
            app.StartWallSimulationButton.Text = 'Start Parametric Simulation';
            app.CurrentWallTestStatusTextArea = uitextarea(app.WallTab);
            app.WallTestCyclePanel = uipanel(app.WallTab);
            app.WallTestCyclePanel.Title = 'Wall Test Cycle';
            app.NumberofCyclesSpinner_2Label = uilabel(app.WallTestCyclePanel);
            app.NumberofCyclesSpinner_2Label.Text = 'Number of Cycles ';
            app.NumberofWallCyclesSpinner = uispinner(app.WallTestCyclePanel);
            app.NumberofWallCyclesSpinner.Limits = [0 Inf];
            app.NumberofWallCyclesSpinner.Value = 1;
            app.FullDurrationhLabel = uilabel(app.WallTestCyclePanel);
            app.FullDurrationhLabel.Text = 'Full Durration (h)';
            app.WallFullDurrationEditField = uieditfield(app.WallTestCyclePanel, 'numeric');
            app.WallFullDurrationEditField.Limits = [0 Inf];
            app.WallFullDurrationEditField.Value = 14;
            app.TimestepsEditFieldLabel_2 = uilabel(app.WallTestCyclePanel);
            app.TimestepsEditFieldLabel_2.Text = 'Timestep (s)';
            app.WallTimestepEditField = uieditfield(app.WallTestCyclePanel, 'numeric');
            app.WallTimestepEditField.Limits = [1e-06 Inf];
            app.WallTimestepEditField.Value = 60;
            app.EmptyDurrationhLabel = uilabel(app.WallTestCyclePanel);
            app.EmptyDurrationhLabel.Text = 'Empty Durration (h)';
            app.WallEmptyDurrationEditField = uieditfield(app.WallTestCyclePanel, 'numeric');
            app.WallEmptyDurrationEditField.Limits = [0 Inf];
            app.WallEmptyDurrationEditField.Value = 10;
            app.WallTestHeatTransferParametersPanel = uipanel(app.WallTab);
            app.WallTestHeatTransferParametersPanel.Title = 'Heat Transfer Parameters';
            app.ParticleInletTemperatureCLabel_2 = uilabel(app.WallTestHeatTransferParametersPanel);
            app.ParticleInletTemperatureCLabel_2.Text = 'Particle Inlet Temperature (°C)';
            app.ParticleInletTemperature_2 = uieditfield(app.WallTestHeatTransferParametersPanel, 'numeric');
            app.ParticleInletTemperature_2.Value = 800;
            app.AmbientHeatTransferCoefficientWm2KLabel_3 = uilabel(app.WallTestHeatTransferParametersPanel);
            app.AmbientHeatTransferCoefficientWm2KLabel_3.Text = 'Ambient Heat Transfer Coefficient (W/m2K)';
            app.AmbientHeatTransferCoefficient_2 = uieditfield(app.WallTestHeatTransferParametersPanel, 'numeric');
            app.AmbientHeatTransferCoefficient_2.Limits = [1e-05 Inf];
            app.AmbientHeatTransferCoefficient_2.Value = 5;
            app.InternalHeatTransferCoefficientWm2KLabel_2 = uilabel(app.WallTestHeatTransferParametersPanel);
            app.InternalHeatTransferCoefficientWm2KLabel_2.Text = 'Internal Heat Transfer Coefficient (W/m2K)';
            app.InternalHeatTransferCoefficient_2 = uieditfield(app.WallTestHeatTransferParametersPanel, 'numeric');
            app.InternalHeatTransferCoefficient_2.Limits = [1e-05 Inf];
            app.InternalHeatTransferCoefficient_2.Value = 5;
            app.AmbientAirTemperatureCLabel_2 = uilabel(app.WallTestHeatTransferParametersPanel);
            app.AmbientAirTemperatureCLabel_2.Text = 'Ambient Air Temperature (°C)';
            app.AmbientAirTemperature_2 = uieditfield(app.WallTestHeatTransferParametersPanel, 'numeric');
            app.AmbientAirTemperature_2.Value = 20;
            app.ParticleThermalConductivityWmKLabel_3 = uilabel(app.WallTestHeatTransferParametersPanel);
            app.ParticleThermalConductivityWmKLabel_3.Text = 'Particle Thermal Conductivity (W/mK)';
            app.ParticleThermalConductivity_2 = uieditfield(app.WallTestHeatTransferParametersPanel, 'numeric');
            app.ParticleThermalConductivity_2.Limits = [1e-05 Inf];
            app.ParticleThermalConductivity_2.Value = 0.4;
            app.PackedParticleBulkDensitykgm3Label_3 = uilabel(app.WallTestHeatTransferParametersPanel);
            app.PackedParticleBulkDensitykgm3Label_3.Text = 'Packed Particle Bulk Density (kg/m3)';
            app.ParticleDensity_2 = uieditfield(app.WallTestHeatTransferParametersPanel, 'numeric');
            app.ParticleDensity_2.Limits = [1e-05 Inf];
            app.ParticleDensity_2.Value = 2000;
            app.ParticleSpecificHeatCapacityJkgKLabel_3 = uilabel(app.WallTestHeatTransferParametersPanel);
            app.ParticleSpecificHeatCapacityJkgKLabel_3.Text = 'Particle Specific Heat Capacity (J/kgK)';
            app.ParticleHeatCapacity_2 = uieditfield(app.WallTestHeatTransferParametersPanel, 'numeric');
            app.ParticleHeatCapacity_2.Limits = [1e-05 Inf];
            app.ParticleHeatCapacity_2.Value = 1026;
            app.BaseTab = uitab(app.InsulationTestingTabGroup);
            app.BaseTab.Title = 'Base';
            app.RoofTab = uitab(app.InsulationTestingTabGroup);
            app.RoofTab.Title = 'Roof';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Disclaimer.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GTS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end